classdef GUI_source_code < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ImagesPanel                     matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        ImagebitdepthDropDown           matlab.ui.control.DropDown
        ImagebitdepthDropDownLabel      matlab.ui.control.Label
        ImageformatDropDown             matlab.ui.control.DropDown
        ImageformatDropDownLabel        matlab.ui.control.Label
        ImageFolderButton               matlab.ui.control.Button
        PostprocessingPanel             matlab.ui.container.Panel
        ExportpathEditField             matlab.ui.control.EditField
        ExportpathEditFieldLabel        matlab.ui.control.Label
        ConverttophysicalworldunitsasRofTdatamatPanel  matlab.ui.container.Panel
        ExportButton_2                  matlab.ui.control.Button
        um2pxEditField                  matlab.ui.control.NumericEditField
        um2pxEditFieldLabel             matlab.ui.control.Label
        FPSEditField                    matlab.ui.control.NumericEditField
        FPSEditFieldLabel               matlab.ui.control.Label
        ExporttoR_datamatPanel          matlab.ui.container.Panel
        UsedefaultpathButton            matlab.ui.control.Button
        StoretoanotherpathButton        matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PretuneTab                      matlab.ui.container.Tab
        ClearandrefreshButton           matlab.ui.control.Button
        RemoveconnectedareapixelEditField  matlab.ui.control.NumericEditField
        RemoveconnectedareapixelEditFieldLabel  matlab.ui.control.Label
        FittingandPreviewButton         matlab.ui.control.Button
        ImageEditField                  matlab.ui.control.NumericEditField
        ImageEditFieldLabel             matlab.ui.control.Label
        LEditFieldLabel_4               matlab.ui.control.Label
        LEditField_D1                   matlab.ui.control.NumericEditField
        LEditFieldLabel_3               matlab.ui.control.Label
        LEditField_U1                   matlab.ui.control.NumericEditField
        LEditFieldLabel_2               matlab.ui.control.Label
        LEditField_R1                   matlab.ui.control.NumericEditField
        LEditField_L1                   matlab.ui.control.NumericEditField
        LEditFieldLabel                 matlab.ui.control.Label
        ROIButton                       matlab.ui.control.Button
        ImagethresholdEditField         matlab.ui.control.NumericEditField
        ImagethresholdEditFieldLabel    matlab.ui.control.Label
        ManualTab                       matlab.ui.container.Tab
        ClearandrefreshButton_3         matlab.ui.control.Button
        ClickatleastthreepointsLabel    matlab.ui.control.Label
        ManuallyselectbubbleedgepointsButton  matlab.ui.control.Button
        ImageEditField_2                matlab.ui.control.NumericEditField
        ImageEditField_2Label_2         matlab.ui.control.Label
        ImagethresholdEditField_3       matlab.ui.control.NumericEditField
        ImagethresholdEditField_3Label  matlab.ui.control.Label
        AutomaticTab                    matlab.ui.container.Tab
        RealtimeplayCheckBox            matlab.ui.control.CheckBox
        LoadtunedparametersButton       matlab.ui.control.Button
        ClearandrefreshButton_2         matlab.ui.control.Button
        endNum                          matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        RemoveconnectedareapixelEditField_2  matlab.ui.control.NumericEditField
        RemoveconnectedareapixelEditField_2Label  matlab.ui.control.Label
        startNum                        matlab.ui.control.NumericEditField
        ImageEditField_2Label           matlab.ui.control.Label
        ROIButton_2                     matlab.ui.control.Button
        LEditFieldLabel_7               matlab.ui.control.Label
        LEditField_R3                   matlab.ui.control.NumericEditField
        LEditField_L3                   matlab.ui.control.NumericEditField
        LEditField_7Label               matlab.ui.control.Label
        LEditFieldLabel_6               matlab.ui.control.Label
        LEditField_U3                   matlab.ui.control.NumericEditField
        LEditFieldLabel_5               matlab.ui.control.Label
        LEditField_D3                   matlab.ui.control.NumericEditField
        ImagethresholdEditField_2       matlab.ui.control.NumericEditField
        ImagethresholdEditField_2Label  matlab.ui.control.Label
        FittingandPreviewButton_2       matlab.ui.control.Button
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        UIAxes_binary                   matlab.ui.control.UIAxes
        UIAxes_Rtcurve                  matlab.ui.control.UIAxes
        UIAxes_raw                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        imageNo = 1;
        imageTotalNum;
        images;
        folderPath;
        ImgThr;
        imageFormat = 'tiff'; % default
        gridx;
        gridy;
        par;
        Radius;
        Radius_backup;
        CircleFitPar;
        CircleXY;
        ImgGrayScaleMax = 2^16-1;   % Double check image bit depth
        ImgGrayScaleMaxuint8 = 255; % Do not change this line
        TiffDiffGauss;
        removingFactor = 90;
        BigOrSmall = 0;
        removeobjradius = 0;
        pixelScale;
        um2px;
        FPS;
        savePath;
        savePath2;
        saveFormat;
        ParameterTuneOrNot = 0;
        stopiter;
        realtimePlayOrNot;
    end

    methods (Access = private)

        function LoadParas(app)
            switch app.TabGroup.SelectedTab
                case app.PretuneTab
                    app.ImgThr = app.ImagethresholdEditField.Value;
                    app.gridy = [app.LEditField_L1.Value,app.LEditField_R1.Value];
                    app.gridx = [app.LEditField_U1.Value,app.LEditField_D1.Value];
                    app.imageNo = app.ImageEditField.Value;;
                    app.imageFormat = app.ImageformatDropDown.Value;
                    app.removingFactor = app.RemoveconnectedareapixelEditField.Value;
                case app.ManualTab
                    app.ImgThr = app.ImagethresholdEditField_3.Value;
                    app.imageNo = app.ImageEditField_2.Value;;

                case app.AutomaticTab
                    app.ImgThr = app.ImagethresholdEditField_2.Value;
                    app.gridy = [app.LEditField_L3.Value,app.LEditField_R3.Value];
                    app.gridx = [app.LEditField_U3.Value,app.LEditField_D3.Value];
                    app.imageNo = app.startNum.Value;
                    app.imageTotalNum = app.endNum.Value;
                    app.imageFormat = app.ImageformatDropDown.Value;
                    app.removingFactor = app.RemoveconnectedareapixelEditField_2.Value;
                    app.realtimePlayOrNot = app.RealtimeplayCheckBox.Value;
            end
            app.um2px = app.um2pxEditField.Value;
            app.FPS = app.FPSEditField.Value;
        end


        % You already have binary current image, then do the circle detect
        % and fitting (display) for just one frame
        function PreviewSingleImage(app)

            bw=imbinarize(uint8(app.TiffDiffGauss(app.gridx(1):app.gridx(2),app.gridy(1):app.gridy(2))), ...
                'adaptive','ForegroundPolarity','dark','Sensitivity',0.55);

            if app.realtimePlayOrNot == 0 && app.imageNo ~= app.endNum.Value;
            else
                title(app.UIAxes_raw, ['Image # ',num2str(app.imageNo)]);
                title(app.UIAxes_binary, ['Image # ',num2str(app.imageNo)]);

                cla(app.UIAxes_binary);
                cla(app.UIAxes_raw);
                imshow(bw,'Parent',app.UIAxes_binary)
            end
            % remove all object containing fewer than 30 pixels
            bw = bwareaopen(bw,app.removingFactor); %imshow(bw)

            if app.removeobjradius > 1
                % fill a gap in the pen's cap
                se = strel('disk',app.removeobjradius);
                bw = imclose(bw,se);

                imshow(bw);

                % fill any holes, so that regionprops can be used to estimate
                % the area enclosed by each of the boundaries
                bw = imfill(bw,'holes'); imshow(bw)
                bw = imfill(bw,'holes'); imshow(bw)
            end

            if app.BigOrSmall == 1 % Big bubble
                % TBD
            else

                %%%%%%% Using conv2 to remove noise near circle borders %%%%%%%%
                bws = bw; % bws = conv2(double(bw),ones(9,9),'same');
                % figure, imshow(bws)
                % se = strel('disk',10);
                % bws2 = imclose(bws,se); bws2 = imfill(bws2,'holes'); % figure, imshow(bws2)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % try
                props = regionprops(bw,'Area','PixelIdxList','MajorAxisLength','MinorAxisLength');
                for tempjj = 1:length(props)
                    if props(tempjj).MajorAxisLength > 2.2*min(size(bws)) || props(tempjj).MajorAxisLength > 1.6*props(tempjj).MinorAxisLength
                        props(tempjj).Area = 0;
                    end
                end
                [~,indexOfMax] = max([props.Area]);

                % approximateRadius = 0.5*props(indexOfMax).MajorAxisLength;
                largestBlobIndexes  = props(indexOfMax).PixelIdxList;

                bws2 = false(size(bws));
                bws2(largestBlobIndexes) = 1; %Only keep the largest blob area
                % figure, imshow(bws2);

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                bws2 = bwareaopen(double(1-bws2),40); %Remove small objects from binary image
                bws2 = 1 - bws2;  %Flip white and black colors
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                edgebws2 = edge(bws2); %Find edges
                % figure; imshow(edgebws2); title('Edge detection');

                [row,col] = find(edgebws2==1);

                XY = [row+app.gridx(1)-1,col+app.gridy(1)-1]; %Find XY coordinates at edges

                % catch
                %     XY = [];
                % end
            end
            try
                currentPar=CircleFitByTaubin(app,XY);
            catch
                currentPar = [0,0,0];
            end
            app.Radius(app.imageNo) = currentPar(3);
            app.CircleFitPar(app.imageNo,:) = [currentPar(1),currentPar(2)];
            app.CircleXY{app.imageNo} = XY;

            if app.realtimePlayOrNot == 0 && app.imageNo ~= app.endNum.Value
            else
                % Display detected circle
                imshow(imread(app.images{app.imageNo},1),'Parent', app.UIAxes_raw,'DisplayRange',[0,app.ImgGrayScaleMax]);
                %imshow(TiffDiffNorm,'DisplayRange',[0,ImgGrayScaleMaxuint8]);
                hold(app.UIAxes_raw, 'on');
                viscircles(app.UIAxes_raw,[currentPar(2),currentPar(1)],currentPar(3),'Color','b');
                plot(XY(:,2),XY(:,1),'r.','Parent', app.UIAxes_raw);
            end

        end

        % Generate binary image
        function GetCurrentbinaryImage(app)
            tempk = app.imageNo;
            f = double( (imread(app.images{tempk},1))); f=f(:);
            TiffRef = imgaussfilt( double( (imread(app.images{1},1))), 2 );
            TiffRef = imgaussfilt( imgaussfilt(TiffRef), 2 );
            TiffCurrent = imgaussfilt( double( (imread(app.images{tempk},1))), 2  );
            TiffCurrent = imgaussfilt( imgaussfilt( TiffCurrent), 2 );
            TiffDiff = TiffCurrent ;
            TiffDiffNorm = app.ImgGrayScaleMaxuint8/(max(TiffDiff(:))-min(TiffDiff(:)))*(TiffDiff-min(TiffDiff(:)));
            app.TiffDiffGauss = imgaussfilt( imgaussfilt( imgaussfilt( TiffDiffNorm, 1 ), 1), 1);
            app.TiffDiffGauss(app.TiffDiffGauss>(app.ImgThr-min(TiffDiff(:)))/(max(TiffDiff(:))-min(TiffDiff(:)))*app.ImgGrayScaleMaxuint8) = app.ImgGrayScaleMaxuint8;


        end

        function PreviewAllImage(app)


        end

        function LoadParasBasedonPretune_mode2(app)
            % Update para based on mode 1
            app.ImagethresholdEditField_3.value =  app.ImagethresholdEditField.value;


        end

        function LoadParasBasedonPretune_mode3(app)
            % Update para based on mode 1
            app.ImagethresholdEditField_2.Value =  app.ImagethresholdEditField.Value;


        end

        % Display certain time Radius_array
        function plotNewPoints(app)
            hold(app.UIAxes_Rtcurve, 'on');
            plot(app.imageNo,app.Radius(app.imageNo),'r+','Parent',app.UIAxes_Rtcurve);
        end

        % Display certain time Radius_array
        function plotAllPoints(app)
            hold(app.UIAxes_Rtcurve, 'on');
            plot([app.startNum.Value:app.endNum.Value],app.Radius(app.startNum.Value:app.endNum.Value),'r+','Parent',app.UIAxes_Rtcurve);
        end

        function    deleteOldPoints(app) % Hide old points
            hold(app.UIAxes_Rtcurve, 'on');
            plot(app.imageNo,app.Radius(app.imageNo),'w+','Parent',app.UIAxes_Rtcurve);

        end

        %
        function Par = CircleFitByTaubin(app,XY)

            %--------------------------------------------------------------------------
            %
            %     Circle fit by Taubin
            %      G. Taubin, "Estimation Of Planar Curves, Surfaces And Nonplanar
            %                  Space Curves Defined By Implicit Equations, With
            %                  Applications To Edge And Range Image Segmentation",
            %      IEEE Trans. PAMI, Vol. 13, pages 1115-1138, (1991)
            %
            %     Input:  XY(n,2) is the array of coordinates of n points x(i)=XY(i,1), y(i)=XY(i,2)
            %
            %     Output: Par = [a b R] is the fitting circle:
            %                           center (a,b) and radius R
            %
            %     Note: this fit does not use built-in matrix functions (except "mean"),
            %           so it can be easily programmed in any programming language
            %
            %--------------------------------------------------------------------------

            n = size(XY,1);      % number of data points

            centroid = mean(XY,1);   % the centroid of the data set

            %     computing moments (note: all moments will be normed, i.e. divided by n)

            Mxx = 0; Myy = 0; Mxy = 0; Mxz = 0; Myz = 0; Mzz = 0;

            for i=1:n
                Xi = XY(i,1) - centroid(1);  %  centering data
                Yi = XY(i,2) - centroid(2);  %  centering data
                Zi = Xi*Xi + Yi*Yi;
                Mxy = Mxy + Xi*Yi;
                Mxx = Mxx + Xi*Xi;
                Myy = Myy + Yi*Yi;
                Mxz = Mxz + Xi*Zi;
                Myz = Myz + Yi*Zi;
                Mzz = Mzz + Zi*Zi;
            end

            Mxx = Mxx/n;
            Myy = Myy/n;
            Mxy = Mxy/n;
            Mxz = Mxz/n;
            Myz = Myz/n;
            Mzz = Mzz/n;

            %    computing the coefficients of the characteristic polynomial

            Mz = Mxx + Myy;
            Cov_xy = Mxx*Myy - Mxy*Mxy;
            A3 = 4*Mz;
            A2 = -3*Mz*Mz - Mzz;
            A1 = Mzz*Mz + 4*Cov_xy*Mz - Mxz*Mxz - Myz*Myz - Mz*Mz*Mz;
            A0 = Mxz*Mxz*Myy + Myz*Myz*Mxx - Mzz*Cov_xy - 2*Mxz*Myz*Mxy + Mz*Mz*Cov_xy;
            A22 = A2 + A2;
            A33 = A3 + A3 + A3;

            xnew = 0;
            ynew = 1e+20;
            epsilon = 1e-12;
            IterMax = 20;

            % Newton's method starting at x=0

            for iter=1:IterMax
                yold = ynew;
                ynew = A0 + xnew*(A1 + xnew*(A2 + xnew*A3));
                if abs(ynew) > abs(yold)
                    disp('Newton-Taubin goes wrong direction: |ynew| > |yold|');
                    xnew = 0;
                    break;
                end
                Dy = A1 + xnew*(A22 + xnew*A33);
                xold = xnew;
                xnew = xold - ynew/Dy;
                if (abs((xnew-xold)/xnew) < epsilon), break, end
                if (iter >= IterMax)
                    disp('Newton-Taubin will not converge');
                    xnew = nan; %0;%modified to remove non-convergent circles, JBE 20160111
                end
                if (xnew<0.)
                    fprintf(1,'Newton-Taubin negative root:  x=%f\n',xnew);
                    xnew = 0;
                end
            end

            %  computing the circle parameters

            DET = xnew*xnew - xnew*Mz + Cov_xy;
            Center = [Mxz*(Myy-xnew)-Myz*Mxy , Myz*(Mxx-xnew)-Mxz*Mxy]/DET/2;

            Par = [Center+centroid , sqrt(Center*Center'+Mz)];

        end    %    CircleFitByTaubin


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Init
            screen_size = get(0, 'ScreenSize'); % get the size of screen
            app.UIFigure.Position = [1300, screen_size(4)-1000, 1000, 800];
            app.TabGroup.SelectedTab = app.PretuneTab;

        end

        % Callback function: EditField, ImageFolderButton
        function ImageFileButtonPushed(app, event)
            app.imageFormat = app.ImageformatDropDown.Value;
            app.folderPath = uigetdir;
            app.EditField.Value = app.folderPath;

            temp = [app.folderPath,'\*.',app.imageFormat];
            files = dir(temp); % Double check image file type
            app.images = cell(length(files),1);
            for i = 1:length(files)
                app.images{i} = [app.folderPath,'\',files(i).name];
            end
            
            if size(app.images,1) == 0
                errordlg('No valid images loaded, please check image format!', 'Error');
                return
            end
            
            % Initialization
            app.Radius = zeros(1,length(files))-1;
            temp_image = imread(app.images{1});
            app.LEditField_R1.Value = size(temp_image,2);
            app.LEditField_R3.Value = size(temp_image,2);
            app.LEditField_D1.Value = size(temp_image,1) ;
            app.LEditField_D3.Value = size(temp_image,1);


            % For Mode 3
            app.endNum.Value = size(app.images,1);

            % For R-t curve axes
            % X
            xlabel(app.UIAxes_Rtcurve, 'Frame No.');
            app.UIAxes_Rtcurve.XLim = [1,app.endNum.Value];
            xTicks = 1:20:app.endNum.Value;
            app.UIAxes_Rtcurve.XTick = xTicks;
            % Y
            ylabel(app.UIAxes_Rtcurve, 'Radius [Pixels]');
            egImageSize = imread(app.images{1},1);
            app.UIAxes_Rtcurve.YLim = [0,max(size(egImageSize))/2];
            YTicks = 1:20:max(size(egImageSize)/2);
            app.UIAxes_Rtcurve.YTick = YTicks;

        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            mode = app.ModeDropDown.Value;
            switch mode
                case 'Pre-tune'
                    app.TabGroup.SelectedTab = app.PretuneTab;
                case 'Manual'
                    app.TabGroup.SelectedTab = app.ManualTab;
                    % LoadParasBasedonPretune_mode2(app); replaced by the
                    % load_tuned_parameters function.
                case 'Automatic'
                    app.TabGroup.SelectedTab = app.AutomaticTab;
                    % LoadParasBasedonPretune_mode3(app); replaced by the
                    % load_tuned_parameters function.
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)


            selectedMode = app.TabGroup.SelectedTab;
            app.ModeDropDown.Value = selectedMode.Title;

            % We don't do this anymore, this function was replaced by the
            % load_tuned_parameters function.
            % if selectedMode == app.PretuneTab
            %
            % elseif selectedMode == app.ManualTab
            %     LoadParasBasedonPretune_mode2(app);
            % elseif selectedMode == app.AutomaticTab
            %     LoadParasBasedonPretune_mode3(app);
            % end

        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            % Update para
            LoadParas(app);
            % ROI modify and display binary image
            GetCurrentbinaryImage(app); % You will get an binary image -- app.TiffDiffGauss
            imshow(uint8(app.TiffDiffGauss),'Parent', app.UIAxes_binary);
            ROIfigureHandle = figure("Name",'Modify ROI','Position', [800, 500, 600, 400]);
            imshow(uint8(app.TiffDiffGauss));
            [app.gridy(1),app.gridx(1)] = ginput(1);
            [app.gridy(2),app.gridx(2)] = ginput(1);
            close(ROIfigureHandle);

            % Adjust ROI domain
            if app.gridx(1)<1, app.gridx(1) = 1; end
            if app.gridy(1)<1, app.gridy(1) = 1; end
            if app.gridx(2)>size(app.TiffDiffGauss,1), app.gridx(2)=size(app.TiffDiffGauss,1);end
            if app.gridy(2)>size(app.TiffDiffGauss,2), app.gridy(2)=size(app.TiffDiffGauss,2);end

            % app.ROIDomainRedefine = 1;

            app.LEditField_L1.Value = app.gridy(1);
            app.LEditField_R1.Value = app.gridy(2);
            app.LEditField_U1.Value = app.gridx(1);
            app.LEditField_D1.Value = app.gridx(2);


            try
                app.TiffDiffGauss( temp111(2):temp222(2), temp111(1):temp222(1) ) =  255;
                figure, imshow(uint8(app.TiffDiffGauss));
            catch
            end

        end

        % Button pushed function: FittingandPreviewButton
        function FittingandPreviewButtonPushed(app, event)
            % Update para
            LoadParas(app);
            % Display
            GetCurrentbinaryImage(app); % You will get an binary image -- app.TiffDiffGauss
            cla(app.UIAxes_raw);
            deleteOldPoints(app);
            PreviewSingleImage(app);
            plotNewPoints(app);

        end

        % Button pushed function: ROIButton_2
        function ROIButton_2Pushed(app, event)

            % Update para
            LoadParas(app);

            % ROI modify and display binary image
            GetCurrentbinaryImage(app); % You will get an binary image -- app.TiffDiffGauss
            imshow(uint8(app.TiffDiffGauss),'Parent', app.UIAxes_binary);
            ROIfigureHandle = figure("Name",'Modify ROI','Position', [800, 500, 600, 400]);
            imshow(uint8(app.TiffDiffGauss));
            [app.gridy(1),app.gridx(1)] = ginput(1);
            [app.gridy(2),app.gridx(2)] = ginput(1);
            close(ROIfigureHandle);

            % Adjust ROI domain
            if app.gridx(1)<1, app.gridx(1) = 1; end
            if app.gridy(1)<1, app.gridy(1) = 1; end
            if app.gridx(2)>size(app.TiffDiffGauss,1), app.gridx(2)=size(app.TiffDiffGauss,1);end
            if app.gridy(2)>size(app.TiffDiffGauss,2), app.gridy(2)=size(app.TiffDiffGauss,2);end

            app.LEditField_L3.Value = app.gridy(1);
            app.LEditField_R3.Value = app.gridy(2);
            app.LEditField_U3.Value = app.gridx(1);
            app.LEditField_D3.Value = app.gridx(2);

            try
                app.TiffDiffGauss( temp111(2):temp222(2), temp111(1):temp222(1) ) =  255;
                figure, imshow(uint8(app.TiffDiffGauss));
            catch
            end

        end

        % Button pushed function: FittingandPreviewButton_2
        function FittingandPreviewButton_2Pushed(app, event)

            % Update para
            LoadParas(app);

            app.stopiter = true;

            if app.realtimePlayOrNot
                for i = app.startNum.Value : app.endNum.Value

                    % % Update para
                    % LoadParas(app);
                    %
                    %
                    % pause(1e-4)
                    % if app.stopiter
                    %     break
                    %
                    % end

                    GetCurrentbinaryImage(app);
                    deleteOldPoints(app);
                    PreviewSingleImage(app);
                    plotNewPoints(app);

                    pause(0.1)

                    app.imageNo = app.imageNo+1;
                end

            else % no need to wait for point-wise display
                d = uiprogressdlg(app.UIFigure,'Title','Please wait...','Message','Processing...','Cancelable','on');

                for i = app.startNum.Value : app.endNum.Value


                    % % Update para
                    % LoadParas(app);
                    %
                    %
                    % pause(1e-4)
                    % if app.stopiter
                    %     break
                    %
                    % end

                    GetCurrentbinaryImage(app);
                    deleteOldPoints(app);
                    PreviewSingleImage(app);
                    app.imageNo = app.imageNo+1;

                    d.Value = ceil(i-app.startNum.Value)/(app.endNum.Value -app.startNum.Value);
                    d.Message = sprintf('Processing... %d%% Finished!', floor(d.Value*100));
                    pause(0.01)
                    if d.CancelRequested
                        break;
                    end
                end

                plotAllPoints(app);
                close(d);
            end


        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

            % app.saveFormat = app.ExportformatDropDown.Value;
            % Update para
            LoadParas(app);

            CircleR = app.Radius';
            CircleCenterSave = app.CircleFitPar;
            CircleEdgePtSave = app.CircleXY;

            %switch idx
            %case 1 % .mat
            save(app.savePath, 'CircleR','CircleCenterSave','CircleEdgePtSave');
            %case 2 % .csv
            %    writematrix([CircleR, CircleCenterSave], app.savePath );
            %end

        end

        % Button pushed function: ClearandrefreshButton
        function ClearandrefreshButtonPushed(app, event)
            app.Radius_backup = app.Radius;
            app.Radius = zeros(size(app.Radius)) - 1;
            app.CircleFitPar = zeros(length(app.Radius),2);
            app.CircleXY = [];
            cla(app.UIAxes_Rtcurve);
        end

        % Button pushed function: ManuallyselectbubbleedgepointsButton
        function ManuallyselectbubbleedgepointsButtonPushed(app, event)
            % Update para
            LoadParas(app);
            ManualfigureHandle = figure("Name",'Select at least 10 points','Position', [800, 500, 600, 400]);
            imshow(imread(app.images{app.imageNo},1),'DisplayRange',[0,app.ImgGrayScaleMax]);
            [temp_gridy,temp_gridx] = ginput();
            XY = [temp_gridx,temp_gridy];
            close(ManualfigureHandle);

            try
                currentPar=CircleFitByTaubin(app,XY);
            catch
                currentPar = [0,0,0];
            end

            deleteOldPoints(app);
            app.Radius(app.imageNo) = currentPar(3);
            plotNewPoints(app);


            app.CircleFitPar(app.imageNo,:) = [currentPar(1),currentPar(2)];
            app.CircleXY{app.imageNo} = XY;

            % Display detected circle
            title(app.UIAxes_raw, ['Image # ',num2str(app.imageNo)]);
            title(app.UIAxes_binary, ['Image # ',num2str(app.imageNo)]);
            cla(app.UIAxes_binary);
            cla(app.UIAxes_raw);
            imshow(imread(app.images{app.imageNo},1),'Parent', app.UIAxes_raw,'DisplayRange',[0,app.ImgGrayScaleMax]);
            %imshow(TiffDiffNorm,'DisplayRange',[0,ImgGrayScaleMaxuint8]);
            hold(app.UIAxes_raw, 'on');
            viscircles(app.UIAxes_raw,[currentPar(2),currentPar(1)],currentPar(3),'Color','b');
            plot(XY(:,2),XY(:,1),'r.','Parent', app.UIAxes_raw);

        end

        % Value changed function: FPSEditField
        function FPSEditFieldValueChanged(app, event)
            app.FPS = app.FPSEditField.Value;

        end

        % Value changed function: um2pxEditField
        function um2pxEditFieldValueChanged(app, event)
            app.um2px = app.um2pxEditField.Value;

        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)

            % Update para
            LoadParas(app);

            deltax = app.um2px;
            deltat = 1/app.FPS;
            R = app.Radius';

            t = [1:1:length(R)]'; % load data file for RofT
            %figure, plot(t,R,'s');

            [Rmaxtemp,RmaxTimeLoctemp] = max(R); % Find Rmax discrete

            RmaxTimeLoctempList = [RmaxTimeLoctemp-2:RmaxTimeLoctemp+2]'; % Find +- 2 data for future Quadratic interpolation
            RmaxtempList = R(RmaxTimeLoctempList);
            % ====== Do quadratic fitting for a more accurate Rmax ======
            try
                % [fitobj] = fit(RmaxTimeLoctempList, RmaxtempList, 'poly2');
                % p = coeffvalues(fitobj);
                p = polyfit( RmaxTimeLoctempList, RmaxtempList, 2 );

                RmaxTimeLoctemp = -p(2)/2/p(1);
                RmaxAlltemp = (4*p(1)*p(3)-p(2)^2)/(4*p(1));
                % figure, plot(RmaxTimeLoctempList, RmaxtempList,'+-'); hold on; plot(RmaxTimeLoctemp,RmaxAlltemp,'ro');

            catch
                RmaxTimeLoctemp = RmaxTimeLoctemp;
                RmaxAlltemp = Rmaxtemp;
            end

            % ====== Assign variables ======
            R = [R(t<RmaxTimeLoctemp); RmaxAlltemp; R(t>RmaxTimeLoctemp)]*deltax;
            t = [t(t<RmaxTimeLoctemp)-RmaxTimeLoctemp; 0; t(t>RmaxTimeLoctemp)-RmaxTimeLoctemp]*deltat;
            RmaxAll = RmaxAlltemp*deltax;
            RmaxTimeLoc = RmaxTimeLoctemp*deltat;

            %%%%% Please comment this line if you do not want to plot figures %%%%%
            % figure, plot(t,R,'.'); set(gca,'fontsize',24);


            app.savePath2 = [fileparts(app.savePath),'/RofTdata.mat'];

            % ====== Save data file ======
            save(app.savePath2, 't','R','RmaxAll','RmaxTimeLoc');


        end

        % Button pushed function: StoretoanotherpathButton
        function StoretoanotherpathButtonPushed(app, event)
            % Update para
            LoadParas(app);
            [file, path, idx] = uiputfile({'*.mat', 'MAT-files (*.mat)'; '*.csv', 'CSV-files (*.csv)'}, 'Save as');
            app.savePath = fullfile(path, file);
            app.ExportpathEditField.Value = app.savePath;
        end

        % Button pushed function: UsedefaultpathButton
        function UsedefaultpathButtonPushed(app, event)
            % Update para
            LoadParas(app);
            app.savePath = [app.folderPath,'\R_data.mat'];
            app.ExportpathEditField.Value = app.savePath;

        end

        % Value changed function: ExportpathEditField
        function ExportpathEditFieldValueChanged(app, event)
            app.savePath = app.ExportpathEditField.Value;
        end

        % Button pushed function: LoadtunedparametersButton
        function LoadtunedparametersButtonPushed(app, event)

            app.ImagethresholdEditField_2.Value = app.ImagethresholdEditField.Value;
            app.RemoveconnectedareapixelEditField_2.Value = app.RemoveconnectedareapixelEditField.Value;
            app.LEditField_D3.Value = app.LEditField_D1.Value;
            app.LEditField_U3.Value = app.LEditField_U1.Value;
            app.LEditField_R3.Value = app.LEditField_R1.Value;
            app.LEditField_L3.Value = app.LEditField_L1.Value;

            % Load para
            LoadParas(app);


        end

        % Button pushed function: ClearandrefreshButton_3
        function ClearandrefreshButton_3Pushed(app, event)
            app.Radius_backup = app.Radius;
            app.Radius = zeros(size(app.Radius)) - 1;
            app.CircleFitPar = zeros(length(app.Radius),2);
            app.CircleXY = [];
            cla(app.UIAxes_Rtcurve);
        end

        % Button pushed function: ClearandrefreshButton_2
        function ClearandrefreshButton_2Pushed(app, event)
            app.Radius_backup = app.Radius;
            app.Radius = zeros(size(app.Radius)) - 1;
            app.CircleFitPar = zeros(length(app.Radius),2);
            app.CircleXY = [];
            cla(app.UIAxes_Rtcurve);
        end

        % Callback function
        function StopButtonPushed(app, event)
            app.stopiter=false;
        end

        % Value changed function: ImagethresholdEditField_2
        function ImagethresholdEditField_2ValueChanged(app, event)
            app.ImgThr = app.ImagethresholdEditField_2.Value;
        end

        % Value changed function: ImagethresholdEditField
        function ImagethresholdEditFieldValueChanged(app, event)
            app.ImgThr = app.ImagethresholdEditField.Value;

        end

        % Value changed function: ImagethresholdEditField_3
        function ImagethresholdEditField_3ValueChanged(app, event)
            app.ImgThr = app.ImagethresholdEditField_3.Value;

        end

        % Value changed function: ImagebitdepthDropDown
        function ImagebitdepthDropDownValueChanged(app, event)
            app.ImgGrayScaleMax = 2^app.ImagebitdepthDropDown.Value - 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_raw = uiaxes(app.GridLayout);
            title(app.UIAxes_raw, 'Raw image (Red-detected bubble edge; Blue-fitting)      Image # ')
            xlabel(app.UIAxes_raw, 'X')
            ylabel(app.UIAxes_raw, 'Y')
            zlabel(app.UIAxes_raw, 'Z')
            app.UIAxes_Rtcurve = uiaxes(app.GridLayout);
            title(app.UIAxes_Rtcurve, 'Radius - time curve')
            xlabel(app.UIAxes_Rtcurve, 'X')
            ylabel(app.UIAxes_Rtcurve, 'Y')
            zlabel(app.UIAxes_Rtcurve, 'Z')
            app.UIAxes_binary = uiaxes(app.GridLayout);
            title(app.UIAxes_binary, 'Binary Image (selected ROI)   Image # ')
            xlabel(app.UIAxes_binary, 'X')
            ylabel(app.UIAxes_binary, 'Y')
            zlabel(app.UIAxes_binary, 'Z')
            app.ModeDropDownLabel = uilabel(app.GridLayout);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.GridLayout);
            app.ModeDropDown.Items = {'Pre-tune', 'Manual', 'Automatic'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Pre-tune';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PretuneTab = uitab(app.TabGroup);
            app.PretuneTab.Title = 'Pre-tune';
            app.ImagethresholdEditFieldLabel = uilabel(app.PretuneTab);
            app.ImagethresholdEditFieldLabel.Text = 'Image threshold';
            app.ImagethresholdEditField = uieditfield(app.PretuneTab, 'numeric');
            app.ImagethresholdEditField.ValueDisplayFormat = '%.0f';
            app.ImagethresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ImagethresholdEditFieldValueChanged, true);
            app.ImagethresholdEditField.Value = 18000;
            app.ROIButton = uibutton(app.PretuneTab, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROI';
            app.LEditFieldLabel = uilabel(app.PretuneTab);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField_L1 = uieditfield(app.PretuneTab, 'numeric');
            app.LEditField_L1.Limits = [1 Inf];
            app.LEditField_L1.ValueDisplayFormat = '%.0f';
            app.LEditField_L1.Value = 1;
            app.LEditField_R1 = uieditfield(app.PretuneTab, 'numeric');
            app.LEditField_R1.Limits = [1 Inf];
            app.LEditField_R1.ValueDisplayFormat = '%.0f';
            app.LEditField_R1.Value = 1;
            app.LEditFieldLabel_2 = uilabel(app.PretuneTab);
            app.LEditFieldLabel_2.Text = 'R';
            app.LEditField_U1 = uieditfield(app.PretuneTab, 'numeric');
            app.LEditField_U1.Limits = [1 Inf];
            app.LEditField_U1.ValueDisplayFormat = '%.0f';
            app.LEditField_U1.Value = 1;
            app.LEditFieldLabel_3 = uilabel(app.PretuneTab);
            app.LEditFieldLabel_3.Text = 'U';
            app.LEditField_D1 = uieditfield(app.PretuneTab, 'numeric');
            app.LEditField_D1.Limits = [1 Inf];
            app.LEditField_D1.ValueDisplayFormat = '%.0f';
            app.LEditField_D1.Value = 1;
            app.LEditFieldLabel_4 = uilabel(app.PretuneTab);
            app.LEditFieldLabel_4.Text = 'D';
            app.ImageEditFieldLabel = uilabel(app.PretuneTab);
            app.ImageEditFieldLabel.Text = 'Image  #';
            app.ImageEditField = uieditfield(app.PretuneTab, 'numeric');
            app.ImageEditField.Limits = [1 Inf];
            app.ImageEditField.ValueDisplayFormat = '%.0f';
            app.ImageEditField.Value = 1;
            app.FittingandPreviewButton = uibutton(app.PretuneTab, 'push');
            app.FittingandPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @FittingandPreviewButtonPushed, true);
            app.FittingandPreviewButton.Text = 'Fitting and Preview';
            app.RemoveconnectedareapixelEditFieldLabel = uilabel(app.PretuneTab);
            app.RemoveconnectedareapixelEditFieldLabel.Text = 'Remove connected area (pixel #)';
            app.RemoveconnectedareapixelEditField = uieditfield(app.PretuneTab, 'numeric');
            app.RemoveconnectedareapixelEditField.ValueDisplayFormat = '%.0f';
            app.RemoveconnectedareapixelEditField.Value = 1000;
            app.ClearandrefreshButton = uibutton(app.PretuneTab, 'push');
            app.ClearandrefreshButton.ButtonPushedFcn = createCallbackFcn(app, @ClearandrefreshButtonPushed, true);
            app.ClearandrefreshButton.Text = 'Clear and refresh';
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.ImagethresholdEditField_3Label = uilabel(app.ManualTab);
            app.ImagethresholdEditField_3Label.Text = 'Image threshold';
            app.ImagethresholdEditField_3 = uieditfield(app.ManualTab, 'numeric');
            app.ImagethresholdEditField_3.ValueDisplayFormat = '%.0f';
            app.ImagethresholdEditField_3.ValueChangedFcn = createCallbackFcn(app, @ImagethresholdEditField_3ValueChanged, true);
            app.ImagethresholdEditField_3.Value = 18000;
            app.ImageEditField_2Label_2 = uilabel(app.ManualTab);
            app.ImageEditField_2Label_2.Text = 'Image  #';
            app.ImageEditField_2 = uieditfield(app.ManualTab, 'numeric');
            app.ImageEditField_2.Limits = [1 Inf];
            app.ImageEditField_2.ValueDisplayFormat = '%.0f';
            app.ImageEditField_2.Value = 1;
            app.ManuallyselectbubbleedgepointsButton = uibutton(app.ManualTab, 'push');
            app.ManuallyselectbubbleedgepointsButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallyselectbubbleedgepointsButtonPushed, true);
            app.ManuallyselectbubbleedgepointsButton.Text = 'Manually select bubble edge points';
            app.ClickatleastthreepointsLabel = uilabel(app.ManualTab);
            app.ClickatleastthreepointsLabel.Text = 'Click at least three points';
            app.ClearandrefreshButton_3 = uibutton(app.ManualTab, 'push');
            app.ClearandrefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearandrefreshButton_3Pushed, true);
            app.ClearandrefreshButton_3.Text = 'Clear and refresh';
            app.AutomaticTab = uitab(app.TabGroup);
            app.AutomaticTab.Title = 'Automatic';
            app.FittingandPreviewButton_2 = uibutton(app.AutomaticTab, 'push');
            app.FittingandPreviewButton_2.ButtonPushedFcn = createCallbackFcn(app, @FittingandPreviewButton_2Pushed, true);
            app.FittingandPreviewButton_2.Text = 'Fitting and Preview';
            app.ImagethresholdEditField_2Label = uilabel(app.AutomaticTab);
            app.ImagethresholdEditField_2Label.Text = 'Image threshold';
            app.ImagethresholdEditField_2 = uieditfield(app.AutomaticTab, 'numeric');
            app.ImagethresholdEditField_2.Limits = [1 Inf];
            app.ImagethresholdEditField_2.ValueDisplayFormat = '%.0f';
            app.ImagethresholdEditField_2.ValueChangedFcn = createCallbackFcn(app, @ImagethresholdEditField_2ValueChanged, true);
            app.ImagethresholdEditField_2.Value = 16000;
            app.LEditField_D3 = uieditfield(app.AutomaticTab, 'numeric');
            app.LEditField_D3.Limits = [1 Inf];
            app.LEditField_D3.ValueDisplayFormat = '%.0f';
            app.LEditField_D3.Value = 1;
            app.LEditFieldLabel_5 = uilabel(app.AutomaticTab);
            app.LEditFieldLabel_5.Text = 'D';
            app.LEditField_U3 = uieditfield(app.AutomaticTab, 'numeric');
            app.LEditField_U3.Limits = [1 Inf];
            app.LEditField_U3.ValueDisplayFormat = '%.0f';
            app.LEditField_U3.Value = 1;
            app.LEditFieldLabel_6 = uilabel(app.AutomaticTab);
            app.LEditFieldLabel_6.Text = 'U';
            app.LEditField_7Label = uilabel(app.AutomaticTab);
            app.LEditField_7Label.Text = 'L';
            app.LEditField_L3 = uieditfield(app.AutomaticTab, 'numeric');
            app.LEditField_L3.Limits = [1 Inf];
            app.LEditField_L3.ValueDisplayFormat = '%.0f';
            app.LEditField_L3.Value = 1;
            app.LEditField_R3 = uieditfield(app.AutomaticTab, 'numeric');
            app.LEditField_R3.Limits = [1 Inf];
            app.LEditField_R3.ValueDisplayFormat = '%.0f';
            app.LEditField_R3.Value = 1;
            app.LEditFieldLabel_7 = uilabel(app.AutomaticTab);
            app.LEditFieldLabel_7.Text = 'R';
            app.ROIButton_2 = uibutton(app.AutomaticTab, 'push');
            app.ROIButton_2.ButtonPushedFcn = createCallbackFcn(app, @ROIButton_2Pushed, true);
            app.ROIButton_2.Text = 'ROI';
            app.ImageEditField_2Label = uilabel(app.AutomaticTab);
            app.ImageEditField_2Label.Text = 'Image  #';
            app.startNum = uieditfield(app.AutomaticTab, 'numeric');
            app.startNum.Limits = [1 Inf];
            app.startNum.ValueDisplayFormat = '%.0f';
            app.startNum.Value = 1;
            app.RemoveconnectedareapixelEditField_2Label = uilabel(app.AutomaticTab);
            app.RemoveconnectedareapixelEditField_2Label.Text = 'Remove connected area (pixel #)';
            app.RemoveconnectedareapixelEditField_2 = uieditfield(app.AutomaticTab, 'numeric');
            app.RemoveconnectedareapixelEditField_2.ValueDisplayFormat = '%.0f';
            app.RemoveconnectedareapixelEditField_2.Value = 1000;
            app.toLabel = uilabel(app.AutomaticTab);
            app.toLabel.Text = 'to';
            app.endNum = uieditfield(app.AutomaticTab, 'numeric');
            app.endNum.Limits = [1 Inf];
            app.endNum.ValueDisplayFormat = '%.0f';
            app.endNum.Value = 1;
            app.ClearandrefreshButton_2 = uibutton(app.AutomaticTab, 'push');
            app.ClearandrefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearandrefreshButton_2Pushed, true);
            app.ClearandrefreshButton_2.Text = 'Clear and refresh';
            app.LoadtunedparametersButton = uibutton(app.AutomaticTab, 'push');
            app.LoadtunedparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtunedparametersButtonPushed, true);
            app.LoadtunedparametersButton.Text = 'Load tuned parameters';
            app.RealtimeplayCheckBox = uicheckbox(app.AutomaticTab);
            app.RealtimeplayCheckBox.Text = 'Realtime play';
            app.RealtimeplayCheckBox.Value = true;
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.PostprocessingPanel = uipanel(app.GridLayout);
            app.PostprocessingPanel.Title = 'Post processing';
            app.ExporttoR_datamatPanel = uipanel(app.PostprocessingPanel);
            app.ExporttoR_datamatPanel.Title = 'Export to "R_data.mat"';
            app.ExportButton = uibutton(app.ExporttoR_datamatPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.StoretoanotherpathButton = uibutton(app.ExporttoR_datamatPanel, 'push');
            app.StoretoanotherpathButton.ButtonPushedFcn = createCallbackFcn(app, @StoretoanotherpathButtonPushed, true);
            app.StoretoanotherpathButton.Text = 'Store to another path';
            app.UsedefaultpathButton = uibutton(app.ExporttoR_datamatPanel, 'push');
            app.UsedefaultpathButton.ButtonPushedFcn = createCallbackFcn(app, @UsedefaultpathButtonPushed, true);
            app.UsedefaultpathButton.Text = 'Use default path';
            app.ConverttophysicalworldunitsasRofTdatamatPanel = uipanel(app.PostprocessingPanel);
            app.ConverttophysicalworldunitsasRofTdatamatPanel.Title = 'Convert to physical world units as "RofTdata.mat"';
            app.FPSEditFieldLabel = uilabel(app.ConverttophysicalworldunitsasRofTdatamatPanel);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.ConverttophysicalworldunitsasRofTdatamatPanel, 'numeric');
            app.FPSEditField.Limits = [1 Inf];
            app.FPSEditField.ValueChangedFcn = createCallbackFcn(app, @FPSEditFieldValueChanged, true);
            app.FPSEditField.Value = 1000000;
            app.um2pxEditFieldLabel = uilabel(app.ConverttophysicalworldunitsasRofTdatamatPanel);
            app.um2pxEditFieldLabel.Text = 'um2px';
            app.um2pxEditField = uieditfield(app.ConverttophysicalworldunitsasRofTdatamatPanel, 'numeric');
            app.um2pxEditField.Limits = [0.001 Inf];
            app.um2pxEditField.ValueChangedFcn = createCallbackFcn(app, @um2pxEditFieldValueChanged, true);
            app.um2pxEditField.Value = 3.2;
            app.ExportButton_2 = uibutton(app.ConverttophysicalworldunitsasRofTdatamatPanel, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.ExportpathEditFieldLabel = uilabel(app.PostprocessingPanel);
            app.ExportpathEditFieldLabel.Text = 'Export path';
            app.ExportpathEditField = uieditfield(app.PostprocessingPanel, 'text');
            app.ExportpathEditField.ValueChangedFcn = createCallbackFcn(app, @ExportpathEditFieldValueChanged, true);
            app.ImagesPanel = uipanel(app.GridLayout);
            app.ImagesPanel.Title = 'Images';
            app.ImageFolderButton = uibutton(app.ImagesPanel, 'push');
            app.ImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImageFileButtonPushed, true);
            app.ImageFolderButton.Text = 'Image Folder';
            app.ImageformatDropDownLabel = uilabel(app.ImagesPanel);
            app.ImageformatDropDownLabel.Text = 'Image format';
            app.ImageformatDropDown = uidropdown(app.ImagesPanel);
            app.ImageformatDropDown.Items = {'tiff', 'tif', 'jpeg', 'bmp'};
            app.ImageformatDropDown.Value = 'tiff';
            app.ImagebitdepthDropDownLabel = uilabel(app.ImagesPanel);
            app.ImagebitdepthDropDownLabel.Text = 'Image bit depth';
            app.ImagebitdepthDropDown = uidropdown(app.ImagesPanel);
            app.ImagebitdepthDropDown.Items = {'8', '10', '12', '16'};
            app.ImagebitdepthDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagebitdepthDropDownValueChanged, true);
            app.ImagebitdepthDropDown.Value = '16';
            app.EditField = uieditfield(app.ImagesPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @ImageFileButtonPushed, true);
            app.EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_source_code

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end