classdef GUI_source_code_1_19 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        PreturntheThresholdRemovingfactorslidertomaketheLabel  matlab.ui.control.Label
        LoadexistingR_datamatButton   matlab.ui.control.Button
        ImagesPanel                   matlab.ui.container.Panel
        EditField                     matlab.ui.control.EditField
        ImagebitdepthDropDown         matlab.ui.control.DropDown
        ImagebitdepthDropDownLabel    matlab.ui.control.Label
        ImageformatDropDown           matlab.ui.control.DropDown
        ImageformatDropDownLabel      matlab.ui.control.Label
        ImageFolderButton             matlab.ui.control.Button
        PostprocessingPanel           matlab.ui.container.Panel
        ConverttophysicalworldunitsasRofTdatamatPanel  matlab.ui.container.Panel
        FitRmaxafterframeEditField    matlab.ui.control.NumericEditField
        FitRmaxafterframeEditFieldLabel  matlab.ui.control.Label
        ExportButton_2                matlab.ui.control.Button
        um2pxEditField                matlab.ui.control.NumericEditField
        um2pxEditFieldLabel           matlab.ui.control.Label
        FPSEditField                  matlab.ui.control.NumericEditField
        FPSEditFieldLabel             matlab.ui.control.Label
        ExporttoR_datamatPanel        matlab.ui.container.Panel
        ExportpathEditField           matlab.ui.control.EditField
        ExportpathEditFieldLabel      matlab.ui.control.Label
        UsedefaultpathButton          matlab.ui.control.Button
        StoretoanotherpathButton      matlab.ui.control.Button
        ExportButton                  matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        PretuneTab                    matlab.ui.container.Tab
        RemovingFactorEditField       matlab.ui.control.NumericEditField
        RemovingFactorEditFieldLabel  matlab.ui.control.Label
        ThresholdEditField            matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        RemovingfactorLabel           matlab.ui.control.Label
        SliderConnectedArea           matlab.ui.control.Slider
        SliderThreshold               matlab.ui.control.Slider
        ThresholdLabel                matlab.ui.control.Label
        BubblecrossgreenedgesLabel    matlab.ui.control.Label
        DownEdgeOrNot                 matlab.ui.control.CheckBox
        TopEdgeOrNot                  matlab.ui.control.CheckBox
        RightEdgeOrNot                matlab.ui.control.CheckBox
        LeftEdgeOrNot                 matlab.ui.control.CheckBox
        ClearandrefreshButton         matlab.ui.control.Button
        FittingandPreviewButton       matlab.ui.control.Button
        ImageEditField                matlab.ui.control.NumericEditField
        ImageEditFieldLabel           matlab.ui.control.Label
        LEditFieldLabel_4             matlab.ui.control.Label
        LEditField_D1                 matlab.ui.control.NumericEditField
        LEditFieldLabel_3             matlab.ui.control.Label
        LEditField_U1                 matlab.ui.control.NumericEditField
        LEditFieldLabel_2             matlab.ui.control.Label
        LEditField_R1                 matlab.ui.control.NumericEditField
        LEditField_L1                 matlab.ui.control.NumericEditField
        LEditFieldLabel               matlab.ui.control.Label
        ROIButton                     matlab.ui.control.Button
        ManualTab                     matlab.ui.container.Tab
        ClearandrefreshButton_3       matlab.ui.control.Button
        ClickatleastthreepointsLabel  matlab.ui.control.Label
        ManuallyselectbubbleedgepointsButton  matlab.ui.control.Button
        ImageEditField_2              matlab.ui.control.NumericEditField
        ImageEditField_2Label_2       matlab.ui.control.Label
        AutomaticTab                  matlab.ui.container.Tab
        RealtimeplayCheckBox          matlab.ui.control.CheckBox
        LoadtunedparametersButton     matlab.ui.control.Button
        ClearandrefreshButton_2       matlab.ui.control.Button
        endNum                        matlab.ui.control.NumericEditField
        toLabel                       matlab.ui.control.Label
        startNum                      matlab.ui.control.NumericEditField
        ImageEditField_2Label         matlab.ui.control.Label
        ROIButton_2                   matlab.ui.control.Button
        LEditFieldLabel_7             matlab.ui.control.Label
        LEditField_R3                 matlab.ui.control.NumericEditField
        LEditField_L3                 matlab.ui.control.NumericEditField
        LEditField_7Label             matlab.ui.control.Label
        LEditFieldLabel_6             matlab.ui.control.Label
        LEditField_U3                 matlab.ui.control.NumericEditField
        LEditFieldLabel_5             matlab.ui.control.Label
        LEditField_D3                 matlab.ui.control.NumericEditField
        FittingandPreviewButton_2     matlab.ui.control.Button
        ModeDropDown                  matlab.ui.control.DropDown
        ModeDropDownLabel             matlab.ui.control.Label
        UIAxes_binary                 matlab.ui.control.UIAxes
        UIAxes_Rtcurve                matlab.ui.control.UIAxes
        UIAxes_raw                    matlab.ui.control.UIAxes
    end


    properties (Access = public)
        imageNo = 1;
        imageTotalNum;
        images;
        folderPath;
        ImgThr;
        imageFormat = 'tiff'; % default
        gridx;
        gridy;
        par;
        Radius;
        Radius_backup;
        CircleFitPar;
        CircleXY;
        ImgGrayScaleMax = 2^16-1;   % Double check image bit depth
        ImgGrayScaleMaxuint8 = 255; % Do not change this line
        % TiffDiffGauss;
        removingFactor = 90;
        BigOrSmall = 0;
        removeobjradius = 0;
        pixelScale;
        um2px;
        FPS;
        Rmax_Fit_Length;
        savePath;
        savePath2;
        saveFormat;
        ParameterTuneOrNot = 0;
        stopiter;
        realtimePlayOrNot;
        bubbleCrossEdges = [0 0 0 0];
        cur_img;
        cur_img_binary;
        cur_img_ROI;
        cur_img_binary_ROI;        
    end

    methods (Access = private)

        %% UPDATE ALL PARAMETERS
        function LoadParas(app)
            switch app.TabGroup.SelectedTab
                case app.PretuneTab
                    app.ImgThr = app.SliderThreshold.Value/100;
                    app.gridy = [app.LEditField_L1.Value,app.LEditField_R1.Value];
                    app.gridx = [app.LEditField_U1.Value,app.LEditField_D1.Value];
                    app.imageNo = app.ImageEditField.Value;
                    app.imageFormat = app.ImageformatDropDown.Value;
                    app.removingFactor = round(app.SliderConnectedArea.Value/100 * app.gridx(2) * app.gridy(2));
                    app.realtimePlayOrNot = app.RealtimeplayCheckBox.Value;
                    app.bubbleCrossEdges = [app.TopEdgeOrNot.Value, app.RightEdgeOrNot.Value, app.DownEdgeOrNot.Value, app.LeftEdgeOrNot.Value];
                case app.ManualTab
                    %app.ImgThr = app.ImagethresholdEditField_3.Value;
                    app.imageNo = app.ImageEditField_2.Value;
                    app.bubbleCrossEdges = [app.TopEdgeOrNot.Value, app.RightEdgeOrNot.Value, app.DownEdgeOrNot.Value, app.LeftEdgeOrNot.Value];

                case app.AutomaticTab
                    %app.ImgThr = app.ImagethresholdEditField_2.Value;
                    app.gridy = [app.LEditField_L3.Value,app.LEditField_R3.Value];
                    app.gridx = [app.LEditField_U3.Value,app.LEditField_D3.Value];
                    app.imageNo = app.startNum.Value;
                    app.imageTotalNum = app.endNum.Value;
                    app.imageFormat = app.ImageformatDropDown.Value;
                    app.removingFactor = round(app.SliderConnectedArea.Value/100 * app.gridx(2) * app.gridy(2));
                    app.realtimePlayOrNot = app.RealtimeplayCheckBox.Value;
                    app.bubbleCrossEdges = [app.TopEdgeOrNot.Value, app.RightEdgeOrNot.Value, app.DownEdgeOrNot.Value, app.LeftEdgeOrNot.Value];

            end
            app.um2px = app.um2pxEditField.Value;
            app.FPS = app.FPSEditField.Value;
            app.Rmax_Fit_Length = app.FitRmaxafterframeEditField.Value;
        end


        %% You already have binary current image, then do the circle detect
        % and fitting (display) for just one frame
        function PreviewSingleImage(app)

            % Automatically binarize
           % bw=imbinarize(uint8(app.ImgGrayScaleMaxuint8 * app.cur_img(app.gridx(1):app.gridx(2),app.gridy(1):app.gridy(2),1)), ...
           %      'adaptive','ForegroundPolarity','dark','Sensitivity',0.55);
           % figure; imshow(app.cur_img_binary)

            bw = app.cur_img_binary_ROI;
            %figure; imshow(bw)


            %% Remove all object containing fewer than removingFactor set by users
            %% Expand the image to double size in case the connected area inside bubble is larger than background connected area.

            [originalRows, originalCols] = size(bw);
            bubbleNonCrossEdges = ~app.bubbleCrossEdges; 
            % Element_1,2,3,4 = TOP, RIGHT, DOWN, LEFT 
            % e.g. bubbleCrossEdges = [1 1 0 0] means the bubble will cross top and right
            % edges during process
            ExpandDirection = find(bubbleNonCrossEdges == 1); ExpandDirection = ExpandDirection(1); % we can expand image along each allowable direction, use the first direction
            
            if ExpandDirection == 1
                expandedBw = ones(originalRows * 2, originalCols);
                expandedBw(originalRows+1:2*originalRows,1:originalCols) = bw;
            elseif ExpandDirection == 2
                expandedBw = ones(originalRows, originalCols *2);
                expandedBw(1:originalRows,1:originalCols) = bw;
            elseif ExpandDirection == 3
                expandedBw = ones(originalRows * 2, originalCols);
                expandedBw(1:originalRows,1:originalCols) = bw;
            elseif ExpandDirection == 4
                expandedBw = ones(originalRows, originalCols *2);
                expandedBw(1:originalRows,originalCols+1:2*originalCols) = bw;
            end

            % figure; imshow(expandedBw)
            %app.removingFactor = round(app.SliderConnectedArea.Value/100 * app.gridx(2) * app.gridy(2));
            expandedBw = bwareaopen(expandedBw,app.removingFactor); %figure; imshow(expandedBw)

            if app.removeobjradius > 1
                % fill a gap in the pen's cap
                se = strel('disk',app.removeobjradius);
                expandedBw = imclose(expandedBw,se);

                imshow(expandedBw);

                % fill any holes, so that regionprops can be used to estimate
                % the area enclosed by each of the boundaries
                expandedBw = imfill(expandedBw,'holes'); imshow(expandedBw)
                expandedBw = imfill(expandedBw,'holes'); imshow(expandedBw)
            end

            if app.BigOrSmall == 1 % Big bubble
                % TBD
            else

                bws = expandedBw; % bws = conv2(double(bw),ones(9,9),'same');
                bws = 1- bws;
                CC = bwconncomp(bws); 
                props = regionprops(CC,'Area','PixelIdxList','MajorAxisLength','MinorAxisLength');
                for tempjj = 1:length(props)
                    if props(tempjj).MajorAxisLength > 2.2*min(size(bws)) || props(tempjj).MajorAxisLength > 1.6*props(tempjj).MinorAxisLength
                        props(tempjj).Area = 0;
                    end
                end
                [~,indexOfMax] = max([props.Area]);

                % approximateRadius = 0.5*props(indexOfMax).MajorAxisLength;
                largestBlobIndexes  = props(indexOfMax).PixelIdxList;

                bws2 = false(size(bws));
                bws2(largestBlobIndexes) = 1; %Only keep the largest blob area
                % figure, imshow(bws2);
    
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                bws2 = bwareaopen(double(1-bws2),40); %Remove small objects from binary image
                bws2 = 1 - bws2;  %Flip white and black colors
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                % Crop to original size
                if ExpandDirection == 1
                    bws2 = bws2(originalRows+1:2*originalRows,1:originalCols);
                elseif ExpandDirection == 2
                    bws2 = bws2(1:originalRows,1:originalCols);
                elseif ExpandDirection == 3
                    bws2 = bws2(1:originalRows,1:originalCols);
                elseif ExpandDirection == 4
                    bws2 = bws2(1:originalRows,originalCols+1:2*originalCols);
                end

                
                % bws2 = bwareaopen(bws2,app.removingFactor/5); %%% 5 is Zach's lucky number, can be edited in the future :)

                edgebws2 = edge(bws2); %Find edges
                % figure; imshow(edgebws2); title('Edge detection');

                [row,col] = find(edgebws2==1);

                XY = [row+app.gridx(1)-1,col+app.gridy(1)-1]; %Find XY coordinates at edges

                % catch
                %     XY = [];
                % end
            end


            try
                currentPar=CircleFitByTaubin(app,XY);
            catch
                currentPar = [0,0,0];
            end
            app.Radius(app.imageNo) = currentPar(3);
            app.CircleFitPar(app.imageNo,:) = [currentPar(1),currentPar(2)];
            app.CircleXY{app.imageNo} = XY;

            % Display binary image
            if app.realtimePlayOrNot == 0 && app.imageNo ~= app.endNum.Value;
            else
                title(app.UIAxes_raw, ['Image # ',num2str(app.imageNo)]);
                title(app.UIAxes_binary, ['Image # ',num2str(app.imageNo)]);

                cla(app.UIAxes_binary);
                cla(app.UIAxes_raw);
                imshow(bws2,'Parent',app.UIAxes_binary)
            end
            
            % Display raw image
            if app.TabGroup.SelectedTab == app.PretuneTab
                % Display detected circle
                imshow(imread(app.images{app.imageNo}),'Parent', app.UIAxes_raw,'DisplayRange',[0,app.ImgGrayScaleMax]);
                hold(app.UIAxes_raw, 'on');
                viscircles(app.UIAxes_raw,[currentPar(2),currentPar(1)],currentPar(3),'Color','b');
                plot(XY(:,2),XY(:,1),'r.','Parent', app.UIAxes_raw);

                % Display ROI
                hold(app.UIAxes_raw, 'on'); 
                rectangle(app.UIAxes_raw, 'Position', [app.gridy(1),app.gridx(1),app.gridy(2)-app.gridy(1),app.gridx(2)-app.gridx(1)], 'EdgeColor', 'green', 'LineWidth', 2);
                hold(app.UIAxes_raw, 'off'); 
            elseif app.TabGroup.SelectedTab == app.AutomaticTab

                if app.realtimePlayOrNot == 0 && app.imageNo ~= app.endNum.Value
                else
                    % Display detected circle
                    imshow(imread(app.images{app.imageNo}),'Parent', app.UIAxes_raw,'DisplayRange',[0,app.ImgGrayScaleMax]);
                    hold(app.UIAxes_raw, 'on');
                    viscircles(app.UIAxes_raw,[currentPar(2),currentPar(1)],currentPar(3),'Color','b');
                    plot(XY(:,2),XY(:,1),'r.','Parent', app.UIAxes_raw);
                                    % Display ROI
                hold(app.UIAxes_raw, 'on'); 
                rectangle(app.UIAxes_raw, 'Position', [app.gridy(1),app.gridx(1),app.gridy(2)-app.gridy(1),app.gridx(2)-app.gridx(1)], 'EdgeColor', 'green', 'LineWidth', 2);
                hold(app.UIAxes_raw, 'off'); 
                end
            end

        end

        %% Generate binary image
        function GetCurrentbinaryImage(app)
            tempk = app.imageNo;
            temp_img = imread(app.images{tempk});
            bitsPerChannel = (str2double(app.ImagebitdepthDropDown.Value)/size(temp_img,3) );
            ref_img = double( (imread(app.images{1}))) / (2 ^ bitsPerChannel- 1);
            temp_cur_img = double( (imread(app.images{tempk}))) /  (2 ^ bitsPerChannel- 1);


            % if rgb, convert it to gray
            if size(ref_img, 3) == 3
                ref_img = rgb2gray(ref_img); 
                temp_cur_img = rgb2gray(temp_cur_img);
            end

            % Smoothing
            %ref_img = imgaussfilt( ref_img, 2 );
            % TiffRef = imgaussfilt( imgaussfilt(TiffRef), 2 ); % Double smoothing
            %temp_cur_img = imgaussfilt( temp_cur_img, 2  );
            %TiffCurrent = imgaussfilt( imgaussfilt( TiffCurrent), 2 ); % Double smoothing

            % Find difference to remove background effects
            % if tempk > 1
            %     TiffDiff = TiffCurrent - TiffRef;
            % else
            %     TiffDiff = TiffCurrent;
            % end
            % imgDiff = cur_img;


            app.cur_img = (max(temp_cur_img(:))-min(temp_cur_img(:)))*(temp_cur_img-min(temp_cur_img(:)));
            %app.cur_img = imgaussfilt( imgaussfilt( imgaussfilt( app.cur_img, 1 ), 1), 1);

            % Threshold applied
            app.cur_img_binary = app.cur_img > app.ImgThr;
            %figure;imshow(app.cur_img)
            %figure;imshow(app.cur_img_binary)

            % ROI applied
            app.cur_img_ROI = app.cur_img(app.gridx(1):app.gridx(2),app.gridy(1):app.gridy(2));
            app.cur_img_binary_ROI = app.cur_img_binary(app.gridx(1):app.gridx(2),app.gridy(1):app.gridy(2));
            %figure;imshow(app.cur_img_ROI)
            %figure;imshow(app.cur_img_binary_ROI)

        end

        function PreviewAllImage(app)


        end

        %% Real-time display for pretune
        function realtimeDisplay_threshold(app, threshold)
            % Update para
            LoadParas(app);
            app.ImgThr = threshold/100;

            % ROI modify and display binary image
            GetCurrentbinaryImage(app); % You will get an binary image -- app.cur_img

            % Display realtime
            title(app.UIAxes_raw, ['Image # ',num2str(app.imageNo)]);
            title(app.UIAxes_binary, ['Image # ',num2str(app.imageNo)]);
            cla(app.UIAxes_binary);
            cla(app.UIAxes_raw);
            imshow(imread(app.images{app.imageNo}),'Parent', app.UIAxes_raw,'DisplayRange',[0,app.ImgGrayScaleMax]);
            imshow(~app.cur_img_binary_ROI,'Parent', app.UIAxes_binary);

            hold(app.UIAxes_raw, 'on'); 
            rectangle(app.UIAxes_raw, 'Position', [app.gridy(1),app.gridx(1),app.gridy(2)-app.gridy(1),app.gridx(2)-app.gridx(1)], 'EdgeColor', 'green', 'LineWidth', 2);
            hold(app.UIAxes_raw, 'off'); 


        end


        function realtimeDisplay_connectedArea(app, removingFactor)
            %% Update para
            LoadParas(app);
            app.removingFactor = round(removingFactor/100 * app.gridx(2) * app.gridy(2));

            % ROI modify and display binary image
            GetCurrentbinaryImage(app); % You will get an binary image -- app.cur_img

            %% Below is part of PreviewSingleImage
            bw = app.cur_img_binary_ROI; %figure; imshow(bw)
            [originalRows, originalCols] = size(bw);
            bubbleNonCrossEdges = ~app.bubbleCrossEdges;
            ExpandDirection = find(bubbleNonCrossEdges == 1); ExpandDirection = ExpandDirection(1); % we can expand image along each allowable direction, use the first direction

            if ExpandDirection == 1
                expandedBw = ones(originalRows * 2, originalCols);
                expandedBw(originalRows+1:2*originalRows,1:originalCols) = bw;
            elseif ExpandDirection == 2
                expandedBw = ones(originalRows, originalCols *2);
                expandedBw(1:originalRows,1:originalCols) = bw;
            elseif ExpandDirection == 3
                expandedBw = ones(originalRows * 2, originalCols);
                expandedBw(1:originalRows,1:originalCols) = bw;
            elseif ExpandDirection == 4
                expandedBw = ones(originalRows, originalCols *2);
                expandedBw(1:originalRows,originalCols+1:2*originalCols) = bw;
            end

            expandedBw = bwareaopen(expandedBw,app.removingFactor); % figure; imshow(expandedBw)
            bws = expandedBw; % bws = conv2(double(bw),ones(9,9),'same');
            bws = 1- bws;
            CC = bwconncomp(bws);
            props = regionprops(CC,'Area','PixelIdxList','MajorAxisLength','MinorAxisLength');
            for tempjj = 1:length(props)
                if props(tempjj).MajorAxisLength > 2.2*min(size(bws)) || props(tempjj).MajorAxisLength > 1.6*props(tempjj).MinorAxisLength
                    props(tempjj).Area = 0;
                end
            end
            [~,indexOfMax] = max([props.Area]);
            largestBlobIndexes  = props(indexOfMax).PixelIdxList;
            bws2 = false(size(bws));
            bws2(largestBlobIndexes) = 1; %Only keep the largest blob area
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            bws2 = bwareaopen(double(1-bws2),40); %Remove small objects from binary image
            bws2 = 1 - bws2;  %Flip white and black colors
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Crop to original size
            if ExpandDirection == 1
                bws2 = bws2(originalRows+1:2*originalRows,1:originalCols);
            elseif ExpandDirection == 2
                bws2 = bws2(1:originalRows,1:originalCols);
            elseif ExpandDirection == 3
                bws2 = bws2(1:originalRows,1:originalCols);
            elseif ExpandDirection == 4
                bws2 = bws2(1:originalRows,originalCols+1:2*originalCols);
            end

            % Display realtime
            title(app.UIAxes_raw, ['Image # ',num2str(app.imageNo)]);
            title(app.UIAxes_binary, ['Image # ',num2str(app.imageNo)]);
            cla(app.UIAxes_binary);
            cla(app.UIAxes_raw);
            imshow(imread(app.images{app.imageNo}),'Parent', app.UIAxes_raw,'DisplayRange',[0,app.ImgGrayScaleMax]);
            imshow(bws2,'Parent', app.UIAxes_binary);

            hold(app.UIAxes_raw, 'on'); 
            rectangle(app.UIAxes_raw, 'Position', [app.gridy(1),app.gridx(1),app.gridy(2)-app.gridy(1),app.gridx(2)-app.gridx(1)], 'EdgeColor', 'green', 'LineWidth', 2);
            hold(app.UIAxes_raw, 'off'); 

        end

        %% Help functions
        function LoadParasBasedonPretune_mode2(app)
            % Update para based on mode 1
            app.ImagethresholdEditField_3.value =  app.ImagethresholdEditField.value;


        end

        function LoadParasBasedonPretune_mode3(app)
            % Update para based on mode 1
            app.ImagethresholdEditField_2.Value =  app.ImagethresholdEditField.Value;


        end

        % Display certain time Radius_array
        function plotNewPoints(app)
            hold(app.UIAxes_Rtcurve, 'on');
            plot(app.imageNo,app.Radius(app.imageNo),'r+','Parent',app.UIAxes_Rtcurve);
        end

        % Display certain time Radius_array
        function plotAllPoints(app)
            hold(app.UIAxes_Rtcurve, 'on');
            plot([app.startNum.Value:app.endNum.Value],app.Radius(app.startNum.Value:app.endNum.Value),'r+','Parent',app.UIAxes_Rtcurve);
        end

        function    deleteOldPoints(app) % Hide old points
            hold(app.UIAxes_Rtcurve, 'on');
            plot(app.imageNo,app.Radius(app.imageNo),'w+','Parent',app.UIAxes_Rtcurve);

        end

        %
        function Par = CircleFitByTaubin(app,XY)

            %--------------------------------------------------------------------------
            %
            %     Circle fit by Taubin
            %      G. Taubin, "Estimation Of Planar Curves, Surfaces And Nonplanar
            %                  Space Curves Defined By Implicit Equations, With
            %                  Applications To Edge And Range Image Segmentation",
            %      IEEE Trans. PAMI, Vol. 13, pages 1115-1138, (1991)
            %
            %     Input:  XY(n,2) is the array of coordinates of n points x(i)=XY(i,1), y(i)=XY(i,2)
            %
            %     Output: Par = [a b R] is the fitting circle:
            %                           center (a,b) and radius R
            %
            %     Note: this fit does not use built-in matrix functions (except "mean"),
            %           so it can be easily programmed in any programming language
            %
            %--------------------------------------------------------------------------

            n = size(XY,1);      % number of data points

            centroid = mean(XY,1);   % the centroid of the data set

            %     computing moments (note: all moments will be normed, i.e. divided by n)

            Mxx = 0; Myy = 0; Mxy = 0; Mxz = 0; Myz = 0; Mzz = 0;

            for i=1:n
                Xi = XY(i,1) - centroid(1);  %  centering data
                Yi = XY(i,2) - centroid(2);  %  centering data
                Zi = Xi*Xi + Yi*Yi;
                Mxy = Mxy + Xi*Yi;
                Mxx = Mxx + Xi*Xi;
                Myy = Myy + Yi*Yi;
                Mxz = Mxz + Xi*Zi;
                Myz = Myz + Yi*Zi;
                Mzz = Mzz + Zi*Zi;
            end

            Mxx = Mxx/n;
            Myy = Myy/n;
            Mxy = Mxy/n;
            Mxz = Mxz/n;
            Myz = Myz/n;
            Mzz = Mzz/n;

            %    computing the coefficients of the characteristic polynomial

            Mz = Mxx + Myy;
            Cov_xy = Mxx*Myy - Mxy*Mxy;
            A3 = 4*Mz;
            A2 = -3*Mz*Mz - Mzz;
            A1 = Mzz*Mz + 4*Cov_xy*Mz - Mxz*Mxz - Myz*Myz - Mz*Mz*Mz;
            A0 = Mxz*Mxz*Myy + Myz*Myz*Mxx - Mzz*Cov_xy - 2*Mxz*Myz*Mxy + Mz*Mz*Cov_xy;
            A22 = A2 + A2;
            A33 = A3 + A3 + A3;

            xnew = 0;
            ynew = 1e+20;
            epsilon = 1e-12;
            IterMax = 20;

            % Newton's method starting at x=0

            for iter=1:IterMax
                yold = ynew;
                ynew = A0 + xnew*(A1 + xnew*(A2 + xnew*A3));
                if abs(ynew) > abs(yold)
                    disp('Newton-Taubin goes wrong direction: |ynew| > |yold|');
                    xnew = 0;
                    break;
                end
                Dy = A1 + xnew*(A22 + xnew*A33);
                xold = xnew;
                xnew = xold - ynew/Dy;
                if (abs((xnew-xold)/xnew) < epsilon), break, end
                if (iter >= IterMax)
                    disp('Newton-Taubin will not converge');
                    xnew = nan; %0;%modified to remove non-convergent circles, JBE 20160111
                end
                if (xnew<0.)
                    fprintf(1,'Newton-Taubin negative root:  x=%f\n',xnew);
                    xnew = 0;
                end
            end

            %  computing the circle parameters

            DET = xnew*xnew - xnew*Mz + Cov_xy;
            Center = [Mxz*(Myy-xnew)-Myz*Mxy , Myz*(Mxx-xnew)-Mxz*Mxy]/DET/2;

            Par = [Center+centroid , sqrt(Center*Center'+Mz)];

        end    %    CircleFitByTaubin


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Init
            screen_size = get(0, 'ScreenSize'); % get the size of screen
            app.UIFigure.Position = [1300, screen_size(4)-1000, 1000, 800];
            app.TabGroup.SelectedTab = app.PretuneTab;

        end

        % Callback function: EditField, ImageFolderButton
        function ImageFileButtonPushed(app, event)
            app.imageFormat = app.ImageformatDropDown.Value;
            app.folderPath = uigetdir;
            app.EditField.Value = app.folderPath;
    
            % ----------- Zach: Support Mac OS -------------------------------------
            % temp = [app.folderPath,'\*.',app.imageFormat];
            temp = fullfile(app.folderPath, ['*.',app.imageFormat]); 

            files = dir(temp); % Double check image file type
            app.images = cell(length(files),1);
            for i = 1:length(files)
                % app.images{i} = [app.folderPath,'\',files(i).name];
                app.images{i} = fullfile(app.folderPath, files(i).name);
            end
            % ---------------------------------------------------------------------

            if size(app.images,1) == 0
                errordlg('No valid images loaded, please check image format!', 'Error');
                return
            end

            % Initialization
            app.Radius = zeros(1,length(files))-1;
            temp_image = imread(app.images{1});
            app.LEditField_R1.Value = size(temp_image,2);
            app.LEditField_R3.Value = size(temp_image,2);
            app.LEditField_D1.Value = size(temp_image,1) ;
            app.LEditField_D3.Value = size(temp_image,1);


            % For Mode 3
            app.endNum.Value = size(app.images,1);

            % For R-t curve axes
            % X
            xlabel(app.UIAxes_Rtcurve, 'Frame No.');
            app.UIAxes_Rtcurve.XLim = [1,app.endNum.Value];
            xTicks = 1:20:app.endNum.Value;
            app.UIAxes_Rtcurve.XTick = xTicks;
            % Y
            ylabel(app.UIAxes_Rtcurve, 'Radius [Pixels]');
            egImageSize = imread(app.images{1});

            app.UIAxes_Rtcurve.YLim = [0,max(size(egImageSize))* 0.7];
            YTicks = 1:20:max(size(egImageSize)/2);
            app.UIAxes_Rtcurve.YTick = YTicks;

        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            mode = app.ModeDropDown.Value;
            switch mode
                case 'Pre-tune'
                    app.TabGroup.SelectedTab = app.PretuneTab;
                case 'Manual'
                    app.TabGroup.SelectedTab = app.ManualTab;
                    % LoadParasBasedonPretune_mode2(app); replaced by the
                    % load_tuned_parameters function.
                case 'Automatic'
                    app.TabGroup.SelectedTab = app.AutomaticTab;
                    % LoadParasBasedonPretune_mode3(app); replaced by the
                    % load_tuned_parameters function.
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)


            selectedMode = app.TabGroup.SelectedTab;
            app.ModeDropDown.Value = selectedMode.Title;

            % We don't do this anymore, this function was replaced by the
            % load_tuned_parameters function.
            % if selectedMode == app.PretuneTab
            %
            % elseif selectedMode == app.ManualTab
            %     LoadParasBasedonPretune_mode2(app);
            % elseif selectedMode == app.AutomaticTab
            %     LoadParasBasedonPretune_mode3(app);
            % end

        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            % Update para
            LoadParas(app);
            % ROI modify and display binary image
            GetCurrentbinaryImage(app); % You will get an binary image -- app.TiffDiffGauss
            imshow(app.cur_img,'Parent', app.UIAxes_raw);
            % ROIfigureHandle = figure("Name",'Modify ROI','Position', [800, 500, 600, 400]);
            % imshow(app.cur_img);

            %%%%%%%%%            
            
            % Define the scaling factor and resize the image
            scale_factor = 2.5;
            resized_img = imresize(app.cur_img, scale_factor, 'bicubic'); % 使用imresize放大图像
            
            % Create a new figure and display the *resized* image
            ROIfigureHandle = figure('Name', 'Modify ROI', 'Position', [800, 500, 600, 400]);
            imshow(resized_img); 
            h = drawrectangle;
            pos = h.Position;
            
            % Get the position and scale it back to the original image coordinates
            pos = pos / scale_factor; 
            
            %%%%%%%%%%%%%%%%%%
  
            app.gridy = [pos(1),pos(1)+pos(3)];
            app.gridx = [pos(2),pos(2)+pos(4)];
            close(ROIfigureHandle);

            % Adjust ROI domain
            if app.gridx(1)<1, app.gridx(1) = 1; end
            if app.gridy(1)<1, app.gridy(1) = 1; end
            if app.gridx(2)>size(app.cur_img,1), app.gridx(2)=size(app.cur_img,1);end
            if app.gridy(2)>size(app.cur_img,2), app.gridy(2)=size(app.cur_img,2);end

            % app.ROIDomainRedefine = 1;

            app.LEditField_L1.Value = app.gridy(1);
            app.LEditField_R1.Value = app.gridy(2);
            app.LEditField_U1.Value = app.gridx(1);
            app.LEditField_D1.Value = app.gridx(2);

            realtimeDisplay_threshold(app,app.SliderThreshold.Value);


        end

        % Button pushed function: FittingandPreviewButton
        function FittingandPreviewButtonPushed(app, event)
            % Update para
            LoadParas(app);
            % Display
            GetCurrentbinaryImage(app); % You will get an binary image -- app.cur_img
            cla(app.UIAxes_raw);
            deleteOldPoints(app);
            PreviewSingleImage(app);
            plotNewPoints(app);

        end

        % Button pushed function: ROIButton_2
        function ROIButton_2Pushed(app, event)

            % Update para
            LoadParas(app);

            % ROI modify and display binary image
            GetCurrentbinaryImage(app); % You will get an binary image -- app.cur_img
            imshow(uint8(app.cur_img),'Parent', app.UIAxes_binary);
            ROIfigureHandle = figure("Name",'Modify ROI','Position', [800, 500, 600, 400]);
            imshow(uint8(app.cur_img));
            [app.gridy(1),app.gridx(1)] = ginput(1);
            [app.gridy(2),app.gridx(2)] = ginput(1);
            close(ROIfigureHandle);

            % Adjust ROI domain
            if app.gridx(1)<1, app.gridx(1) = 1; end
            if app.gridy(1)<1, app.gridy(1) = 1; end
            if app.gridx(2)>size(app.cur_img,1), app.gridx(2)=size(app.cur_img,1);end
            if app.gridy(2)>size(app.cur_img,2), app.gridy(2)=size(app.cur_img,2);end

            app.LEditField_L3.Value = app.gridy(1);
            app.LEditField_R3.Value = app.gridy(2);
            app.LEditField_U3.Value = app.gridx(1);
            app.LEditField_D3.Value = app.gridx(2);

            % try
            %     app.cur_img( temp111(2):temp222(2), temp111(1):temp222(1) ) =  255;
            %     figure, imshow(uint8(app.cur_img));
            % catch
            % end

        end

        % Button pushed function: FittingandPreviewButton_2
        function FittingandPreviewButton_2Pushed(app, event)

            % Update para
            LoadParas(app);

            app.stopiter = true;

            if app.realtimePlayOrNot
                for i = app.startNum.Value : app.endNum.Value

                    % % Update para
                    % LoadParas(app);
                    %
                    %
                    % pause(1e-4)
                    % if app.stopiter
                    %     break
                    %
                    % end

                    GetCurrentbinaryImage(app);
                    deleteOldPoints(app);
                    PreviewSingleImage(app);
                    plotNewPoints(app);

                    pause(0.1)

                    app.imageNo = app.imageNo+1;
                end

            else % no need to wait for point-wise display
                d = uiprogressdlg(app.UIFigure,'Title','Please wait...','Message','Processing...','Cancelable','on');

                for i = app.startNum.Value : app.endNum.Value


                    % % Update para
                    % LoadParas(app);
                    %
                    %
                    % pause(1e-4)
                    % if app.stopiter
                    %     break
                    %
                    % end

                    GetCurrentbinaryImage(app);
                    deleteOldPoints(app);
                    PreviewSingleImage(app);
                    app.imageNo = app.imageNo+1;

                    d.Value = ceil(i-app.startNum.Value)/(app.endNum.Value -app.startNum.Value);
                    d.Message = sprintf('Processing... %d%% Finished!', floor(d.Value*100));
                    pause(0.01)
                    if d.CancelRequested
                        break;
                    end
                end

                plotAllPoints(app);
                close(d);
            end


        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

            % app.saveFormat = app.ExportformatDropDown.Value;
            % Update para
            LoadParas(app);

            CircleR = app.Radius';
            CircleCenterSave = app.CircleFitPar;
            CircleEdgePtSave = app.CircleXY;

            %switch idx
            %case 1 % .mat
            save(app.savePath, 'CircleR','CircleCenterSave','CircleEdgePtSave');
            %case 2 % .csv
            %    writematrix([CircleR, CircleCenterSave], app.savePath );
            %end

        end

        % Button pushed function: ClearandrefreshButton
        function ClearandrefreshButtonPushed(app, event)
            app.Radius_backup = app.Radius;
            app.Radius = zeros(size(app.Radius)) - 1;
            app.CircleFitPar = zeros(length(app.Radius),2);
            app.CircleXY = [];
            cla(app.UIAxes_Rtcurve);
        end

        % Button pushed function: ManuallyselectbubbleedgepointsButton
        function ManuallyselectbubbleedgepointsButtonPushed(app, event)
            % Update para
            LoadParas(app);
            ManualfigureHandle = figure("Name",'Select at least 3 points','Position', [500, 500, 1000, 1000]);
            
            % Define scale factor, read original image, and resize it
            scale_factor = 2.5;
            original_img = imread(app.images{app.imageNo});
            resized_img = imresize(original_img, scale_factor, 'bicubic');

            % Display the resized image
            imshow(resized_img, 'DisplayRange', [0,app.ImgGrayScaleMax]);
            hold on; 

XY = [];
while true
    try
        [x, y, button] = ginput(1);
        
        if isempty(button) || button == 13
            break; % 
        end
        
        XY = [XY; y, x];
        
        plot(x, y, 'ro', 'MarkerSize', 6, 'LineWidth', 1.5);
        
    catch
        disp('User cancelled.');
        break;
    end
end

close(ManualfigureHandle);

% Scale the collected points back to original image coordinates ---
XY = XY / scale_factor;

if size(XY, 1) < 3
    error('Please select at least 3 points!');
end


            try
                currentPar=CircleFitByTaubin(app,XY);
            catch
                currentPar = [0,0,0];
            end

            deleteOldPoints(app);
            app.Radius(app.imageNo) = currentPar(3);
            plotNewPoints(app);


            app.CircleFitPar(app.imageNo,:) = [currentPar(1),currentPar(2)];
            app.CircleXY{app.imageNo} = XY;

            % Display detected circle
            title(app.UIAxes_raw, ['Image # ',num2str(app.imageNo)]);
            title(app.UIAxes_binary, ['Image # ',num2str(app.imageNo)]);
            cla(app.UIAxes_binary);
            cla(app.UIAxes_raw);
            imshow(imread(app.images{app.imageNo}),'Parent', app.UIAxes_raw,'DisplayRange',[0,app.ImgGrayScaleMax]);
            %imshow(TiffDiffNorm,'DisplayRange',[0,ImgGrayScaleMaxuint8]);
            hold(app.UIAxes_raw, 'on');
            viscircles(app.UIAxes_raw,[currentPar(2),currentPar(1)],currentPar(3),'Color','b');
            plot(XY(:,2),XY(:,1),'r.','Parent', app.UIAxes_raw);

        end

        % Value changed function: FPSEditField
        function FPSEditFieldValueChanged(app, event)
            app.FPS = app.FPSEditField.Value;

        end

        % Value changed function: um2pxEditField
        function um2pxEditFieldValueChanged(app, event)
            app.um2px = app.um2pxEditField.Value;

        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)

            % Update para
            LoadParas(app);

            deltax = app.um2px;
            deltat = 1/app.FPS;
            RmaxFitHalfLength = round((app.Rmax_Fit_Length-1)/2);

            R = app.Radius'; R = R(:);

            t = [1:1:length(R)]'; t = t(:); % load data file for RofT
            %figure, plot(t,R,'s');

            [Rmaxtemp,RmaxTimeLoctemp] = max(R); % Find Rmax discrete

            % Check if the Rmax_fit_length is legal
            if RmaxTimeLoctemp-RmaxFitHalfLength < 1
                uialert(app.UIFigure, 'Please check your Rmax_Fit_length!', 'Error');
                return;
            else
                RmaxTimeLoctempList = [RmaxTimeLoctemp-RmaxFitHalfLength:RmaxTimeLoctemp+RmaxFitHalfLength]'; % Find +- 2 data for future Quadratic interpolation
                RmaxtempList = R(RmaxTimeLoctempList);
                % ====== Do quadratic fitting for a more accurate Rmax ======
                try
                    % [fitobj] = fit(RmaxTimeLoctempList, RmaxtempList, 'poly2');
                    % p = coeffvalues(fitobj);
                    p = polyfit( RmaxTimeLoctempList, RmaxtempList, 2 );

                    RmaxTimeLoctemp = -p(2)/2/p(1);
                    RmaxAlltemp = (4*p(1)*p(3)-p(2)^2)/(4*p(1));
                    % figure, plot(RmaxTimeLoctempList, RmaxtempList,'+-'); hold on; plot(RmaxTimeLoctemp,RmaxAlltemp,'ro');

                catch
                    RmaxTimeLoctemp = RmaxTimeLoctemp;
                    RmaxAlltemp = Rmaxtemp;
                end

                % ====== Assign variables ======
                R = [R(t<RmaxTimeLoctemp); RmaxAlltemp; R(t>RmaxTimeLoctemp)]*deltax;
                t = [t(t<RmaxTimeLoctemp)-RmaxTimeLoctemp; 0; t(t>RmaxTimeLoctemp)-RmaxTimeLoctemp]*deltat;
                RmaxAll = RmaxAlltemp*deltax;
                RmaxTimeLoc = RmaxTimeLoctemp*deltat;

                %%%%% Please comment this line if you do not want to plot figures %%%%%
                % figure, plot(t,R,'.'); set(gca,'fontsize',24);


                app.savePath2 = [fileparts(app.savePath),'/RofTdata.mat'];

                % ====== Save data file ======
                save(app.savePath2, 't','R','RmaxAll','RmaxTimeLoc');
            end

        end

        % Button pushed function: StoretoanotherpathButton
        function StoretoanotherpathButtonPushed(app, event)
            % Update para
            LoadParas(app);
            [file, path, idx] = uiputfile({'*.mat', 'MAT-files (*.mat)'; '*.csv', 'CSV-files (*.csv)'}, 'Save as');
            app.savePath = fullfile(path, file);
            app.ExportpathEditField.Value = app.savePath;
        end

        % Button pushed function: UsedefaultpathButton
        function UsedefaultpathButtonPushed(app, event)
            % Update para
            LoadParas(app);
            app.savePath = [app.folderPath,'/R_data.mat'];
            app.ExportpathEditField.Value = app.savePath;

        end

        % Value changed function: ExportpathEditField
        function ExportpathEditFieldValueChanged(app, event)
            app.savePath = app.ExportpathEditField.Value;
        end

        % Button pushed function: LoadtunedparametersButton
        function LoadtunedparametersButtonPushed(app, event)

            %app.ImagethresholdEditField_2.Value = app.ImagethresholdEditField.Value;
            %app.RemoveconnectedareapixelEditField_2.Value = app.RemoveconnectedareapixelEditField.Value;
            app.LEditField_D3.Value = app.LEditField_D1.Value;
            app.LEditField_U3.Value = app.LEditField_U1.Value;
            app.LEditField_R3.Value = app.LEditField_R1.Value;
            app.LEditField_L3.Value = app.LEditField_L1.Value;

            % Load para
            LoadParas(app);


        end

        % Button pushed function: ClearandrefreshButton_3
        function ClearandrefreshButton_3Pushed(app, event)
            app.Radius_backup = app.Radius;
            app.Radius = zeros(size(app.Radius)) - 1;
            app.CircleFitPar = zeros(length(app.Radius),2);
            app.CircleXY = [];
            cla(app.UIAxes_Rtcurve);
        end

        % Button pushed function: ClearandrefreshButton_2
        function ClearandrefreshButton_2Pushed(app, event)
            app.Radius_backup = app.Radius;
            app.Radius = zeros(size(app.Radius)) - 1;
            app.CircleFitPar = zeros(length(app.Radius),2);
            app.CircleXY = [];
            cla(app.UIAxes_Rtcurve);
        end

        % Callback function
        function StopButtonPushed(app, event)
            app.stopiter=false;
        end

        % Callback function
        function ImagethresholdEditField_2ValueChanged(app, event)
            app.ImgThr = app.ImagethresholdEditField_2.Value;
        end

        % Callback function
        function ImagethresholdEditFieldValueChanged(app, event)
            app.ImgThr = app.ImagethresholdEditField.Value;

        end

        % Callback function
        function ImagethresholdEditField_3ValueChanged(app, event)
            app.ImgThr = app.ImagethresholdEditField_3.Value;

        end

        % Value changed function: ImagebitdepthDropDown
        function ImagebitdepthDropDownValueChanged(app, event)
            app.ImgGrayScaleMax = 2^str2double(app.ImagebitdepthDropDown.Value) - 1;
        end

        % Button pushed function: LoadexistingR_datamatButton
        function LoadexistingR_datamatButtonPushed(app, event)

            % Open a dialog box for the user to select a .mat file
            [fileName, app.folderPath] = uigetfile('*.mat', 'Select the R_data.mat file');
            
            % Check if the user cancelled the selection
            if isequal(fileName, 0) || isequal(app.folderPath, 0)
                disp('User cancelled the file selection.');
                return; % Exit the function
            end

            % Construct the full path to the selected file
            fullFilePath = fullfile(app.folderPath, fileName);


            try
                Rt = load(fullFilePath); % Use the new path to load the file
                disp(['File ''', fileName, ''' loaded successfully.']);
            catch
                disp(['An error occurred while loading the file: ', fileName]);
                % It's good practice to also return here if loading fails
                return; 
            end
            
            app.CircleFitPar = Rt.CircleCenterSave;
            app.CircleXY = Rt.CircleEdgePtSave;
            app.Radius = Rt.CircleR;
            app.imageNo = 1:1:length(app.Radius);

            % Update para
            % LoadParas(app);

            % Display
            %GetCurrentbinaryImage(app); % You will get an binary image -- app.TiffDiffGauss
            cla(app.UIAxes_raw);
            deleteOldPoints(app);
            %PreviewSingleImage(app);
            plotNewPoints(app);

        end

        % Value changed function: FitRmaxafterframeEditField
        function FitRmaxafterframeEditFieldValueChanged(app, event)
            app.Rmax_Fit_Length = app.FitRmaxafterframeEditField.Value;

        end

        % Value changed function: ImageEditField
        function ImageEditFieldValueChanged(app, event)
            value = app.ImageEditField.Value;
            realtimeDisplay_connectedArea(app,app.SliderConnectedArea.Value);
        end

        % Value changing function: SliderThreshold
        function SliderThresholdValueChanging(app, event)
            changingValue = event.Value;
            app.ThresholdEditField.Value = changingValue;
            realtimeDisplay_threshold(app,changingValue);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.SliderThreshold.Value = value;
            realtimeDisplay_threshold(app, value);
        end

        % Value changing function: SliderConnectedArea
        function SliderConnectedAreaValueChanging(app, event)
            changingValue = event.Value;
            app.RemovingFactorEditField.Value = changingValue;
            % realtimeDisplay_connectedArea(app,changingValue);
        end

        % Value changed function: SliderConnectedArea
        function SliderConnectedAreaValueChanged(app, event)
            value = app.SliderConnectedArea.Value;
            realtimeDisplay_connectedArea(app,value);
        end

        % Value changed function: RemovingFactorEditField
        function RemovingFactorEditFieldValueChanged(app, event)
            value = app.RemovingFactorEditField.Value;
            app.SliderConnectedArea.Value = value;
            realtimeDisplay_connectedArea(app, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_raw = uiaxes(app.GridLayout);
            title(app.UIAxes_raw, 'Raw image (Red-detected bubble edge; Blue-fitting; Green: ROI)      Image # ')
            xlabel(app.UIAxes_raw, 'X [px]')
            ylabel(app.UIAxes_raw, 'Y [px]')
            zlabel(app.UIAxes_raw, 'Z')
            app.UIAxes_Rtcurve = uiaxes(app.GridLayout);
            title(app.UIAxes_Rtcurve, 'Radius - time curve')
            xlabel(app.UIAxes_Rtcurve, 'Frame No.')
            ylabel(app.UIAxes_Rtcurve, 'Rad [px]')
            zlabel(app.UIAxes_Rtcurve, 'Z')
            app.UIAxes_binary = uiaxes(app.GridLayout);
            title(app.UIAxes_binary, 'Binary Image (selected ROI)   Image # ')
            xlabel(app.UIAxes_binary, 'X [px]')
            ylabel(app.UIAxes_binary, 'Y [px]')
            zlabel(app.UIAxes_binary, 'Z')
            app.ModeDropDownLabel = uilabel(app.GridLayout);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.GridLayout);
            app.ModeDropDown.Items = {'Pre-tune', 'Manual', 'Automatic'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Pre-tune';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PretuneTab = uitab(app.TabGroup);
            app.PretuneTab.Title = 'Pre-tune';
            app.ROIButton = uibutton(app.PretuneTab, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROI';
            app.LEditFieldLabel = uilabel(app.PretuneTab);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField_L1 = uieditfield(app.PretuneTab, 'numeric');
            app.LEditField_L1.Limits = [1 Inf];
            app.LEditField_L1.ValueDisplayFormat = '%.0f';
            app.LEditField_L1.Value = 1;
            app.LEditField_R1 = uieditfield(app.PretuneTab, 'numeric');
            app.LEditField_R1.Limits = [1 Inf];
            app.LEditField_R1.ValueDisplayFormat = '%.0f';
            app.LEditField_R1.Value = 1;
            app.LEditFieldLabel_2 = uilabel(app.PretuneTab);
            app.LEditFieldLabel_2.Text = 'R';
            app.LEditField_U1 = uieditfield(app.PretuneTab, 'numeric');
            app.LEditField_U1.Limits = [1 Inf];
            app.LEditField_U1.ValueDisplayFormat = '%.0f';
            app.LEditField_U1.Value = 1;
            app.LEditFieldLabel_3 = uilabel(app.PretuneTab);
            app.LEditFieldLabel_3.Text = 'U';
            app.LEditField_D1 = uieditfield(app.PretuneTab, 'numeric');
            app.LEditField_D1.Limits = [1 Inf];
            app.LEditField_D1.ValueDisplayFormat = '%.0f';
            app.LEditField_D1.Value = 1;
            app.LEditFieldLabel_4 = uilabel(app.PretuneTab);
            app.LEditFieldLabel_4.Text = 'D';
            app.ImageEditFieldLabel = uilabel(app.PretuneTab);
            app.ImageEditFieldLabel.Text = 'Image  #';
            app.ImageEditField = uieditfield(app.PretuneTab, 'numeric');
            app.ImageEditField.Limits = [1 Inf];
            app.ImageEditField.ValueDisplayFormat = '%.0f';
            app.ImageEditField.ValueChangedFcn = createCallbackFcn(app, @ImageEditFieldValueChanged, true);
            app.ImageEditField.Value = 1;
            app.FittingandPreviewButton = uibutton(app.PretuneTab, 'push');
            app.FittingandPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @FittingandPreviewButtonPushed, true);
            app.FittingandPreviewButton.Text = 'Fitting and Preview';
            app.ClearandrefreshButton = uibutton(app.PretuneTab, 'push');
            app.ClearandrefreshButton.ButtonPushedFcn = createCallbackFcn(app, @ClearandrefreshButtonPushed, true);
            app.ClearandrefreshButton.Text = 'Clear and refresh';
            app.LeftEdgeOrNot = uicheckbox(app.PretuneTab);
            app.LeftEdgeOrNot.Text = 'Left';
            app.RightEdgeOrNot = uicheckbox(app.PretuneTab);
            app.RightEdgeOrNot.Text = 'Right';
            app.TopEdgeOrNot = uicheckbox(app.PretuneTab);
            app.TopEdgeOrNot.Text = 'Top';
            app.DownEdgeOrNot = uicheckbox(app.PretuneTab);
            app.DownEdgeOrNot.Text = 'Down';
            app.BubblecrossgreenedgesLabel = uilabel(app.PretuneTab);
            app.BubblecrossgreenedgesLabel.Text = {'Bubble cross'; 'green edges?'};
            app.ThresholdLabel = uilabel(app.PretuneTab);
            app.ThresholdLabel.Text = 'Threshold';
            app.SliderThreshold = uislider(app.PretuneTab);
            app.SliderThreshold.ValueChangingFcn = createCallbackFcn(app, @SliderThresholdValueChanging, true);
            app.SliderThreshold.Value = 50;
            app.SliderConnectedArea = uislider(app.PretuneTab);
            app.SliderConnectedArea.ValueChangedFcn = createCallbackFcn(app, @SliderConnectedAreaValueChanged, true);
            app.SliderConnectedArea.ValueChangingFcn = createCallbackFcn(app, @SliderConnectedAreaValueChanging, true);
            app.SliderConnectedArea.Value = 50;
            app.RemovingfactorLabel = uilabel(app.PretuneTab);
            app.RemovingfactorLabel.Text = 'Removing  factor';
            app.ThresholdEditFieldLabel = uilabel(app.PretuneTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.PretuneTab, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.RemovingFactorEditFieldLabel = uilabel(app.PretuneTab);
            app.RemovingFactorEditFieldLabel.Text = 'RemovingFactor';
            app.RemovingFactorEditField = uieditfield(app.PretuneTab, 'numeric');
            app.RemovingFactorEditField.ValueChangedFcn = createCallbackFcn(app, @RemovingFactorEditFieldValueChanged, true);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.ImageEditField_2Label_2 = uilabel(app.ManualTab);
            app.ImageEditField_2Label_2.Text = 'Image  #';
            app.ImageEditField_2 = uieditfield(app.ManualTab, 'numeric');
            app.ImageEditField_2.Limits = [1 Inf];
            app.ImageEditField_2.ValueDisplayFormat = '%.0f';
            app.ImageEditField_2.Value = 1;
            app.ManuallyselectbubbleedgepointsButton = uibutton(app.ManualTab, 'push');
            app.ManuallyselectbubbleedgepointsButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallyselectbubbleedgepointsButtonPushed, true);
            app.ManuallyselectbubbleedgepointsButton.Text = 'Manually select bubble edge points';
            app.ClickatleastthreepointsLabel = uilabel(app.ManualTab);
            app.ClickatleastthreepointsLabel.Text = 'Click at least three points';
            app.ClearandrefreshButton_3 = uibutton(app.ManualTab, 'push');
            app.ClearandrefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearandrefreshButton_3Pushed, true);
            app.ClearandrefreshButton_3.Text = 'Clear and refresh';
            app.AutomaticTab = uitab(app.TabGroup);
            app.AutomaticTab.Title = 'Automatic';
            app.FittingandPreviewButton_2 = uibutton(app.AutomaticTab, 'push');
            app.FittingandPreviewButton_2.ButtonPushedFcn = createCallbackFcn(app, @FittingandPreviewButton_2Pushed, true);
            app.FittingandPreviewButton_2.Text = 'Fitting and Preview';
            app.LEditField_D3 = uieditfield(app.AutomaticTab, 'numeric');
            app.LEditField_D3.Limits = [1 Inf];
            app.LEditField_D3.ValueDisplayFormat = '%.0f';
            app.LEditField_D3.Value = 1;
            app.LEditFieldLabel_5 = uilabel(app.AutomaticTab);
            app.LEditFieldLabel_5.Text = 'D';
            app.LEditField_U3 = uieditfield(app.AutomaticTab, 'numeric');
            app.LEditField_U3.Limits = [1 Inf];
            app.LEditField_U3.ValueDisplayFormat = '%.0f';
            app.LEditField_U3.Value = 1;
            app.LEditFieldLabel_6 = uilabel(app.AutomaticTab);
            app.LEditFieldLabel_6.Text = 'U';
            app.LEditField_7Label = uilabel(app.AutomaticTab);
            app.LEditField_7Label.Text = 'L';
            app.LEditField_L3 = uieditfield(app.AutomaticTab, 'numeric');
            app.LEditField_L3.Limits = [1 Inf];
            app.LEditField_L3.ValueDisplayFormat = '%.0f';
            app.LEditField_L3.Value = 1;
            app.LEditField_R3 = uieditfield(app.AutomaticTab, 'numeric');
            app.LEditField_R3.Limits = [1 Inf];
            app.LEditField_R3.ValueDisplayFormat = '%.0f';
            app.LEditField_R3.Value = 1;
            app.LEditFieldLabel_7 = uilabel(app.AutomaticTab);
            app.LEditFieldLabel_7.Text = 'R';
            app.ROIButton_2 = uibutton(app.AutomaticTab, 'push');
            app.ROIButton_2.ButtonPushedFcn = createCallbackFcn(app, @ROIButton_2Pushed, true);
            app.ROIButton_2.Text = 'ROI';
            app.ImageEditField_2Label = uilabel(app.AutomaticTab);
            app.ImageEditField_2Label.Text = 'Image  #';
            app.startNum = uieditfield(app.AutomaticTab, 'numeric');
            app.startNum.Limits = [1 Inf];
            app.startNum.ValueDisplayFormat = '%.0f';
            app.startNum.Value = 1;
            app.toLabel = uilabel(app.AutomaticTab);
            app.toLabel.Text = 'to';
            app.endNum = uieditfield(app.AutomaticTab, 'numeric');
            app.endNum.Limits = [1 Inf];
            app.endNum.ValueDisplayFormat = '%.0f';
            app.endNum.Value = 1;
            app.ClearandrefreshButton_2 = uibutton(app.AutomaticTab, 'push');
            app.ClearandrefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearandrefreshButton_2Pushed, true);
            app.ClearandrefreshButton_2.Text = 'Clear and refresh';
            app.LoadtunedparametersButton = uibutton(app.AutomaticTab, 'push');
            app.LoadtunedparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtunedparametersButtonPushed, true);
            app.LoadtunedparametersButton.Text = 'Load tuned parameters';
            app.RealtimeplayCheckBox = uicheckbox(app.AutomaticTab);
            app.RealtimeplayCheckBox.Text = 'Realtime play';
            app.RealtimeplayCheckBox.Value = true;
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.PostprocessingPanel = uipanel(app.GridLayout);
            app.PostprocessingPanel.Title = 'Post processing';
            app.ExporttoR_datamatPanel = uipanel(app.PostprocessingPanel);
            app.ExporttoR_datamatPanel.Title = 'Export to "R_data.mat"';
            app.ExportButton = uibutton(app.ExporttoR_datamatPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.StoretoanotherpathButton = uibutton(app.ExporttoR_datamatPanel, 'push');
            app.StoretoanotherpathButton.ButtonPushedFcn = createCallbackFcn(app, @StoretoanotherpathButtonPushed, true);
            app.StoretoanotherpathButton.Text = 'Store to another path';
            app.UsedefaultpathButton = uibutton(app.ExporttoR_datamatPanel, 'push');
            app.UsedefaultpathButton.ButtonPushedFcn = createCallbackFcn(app, @UsedefaultpathButtonPushed, true);
            app.UsedefaultpathButton.Text = 'Use default path';
            app.ExportpathEditFieldLabel = uilabel(app.ExporttoR_datamatPanel);
            app.ExportpathEditFieldLabel.Text = 'Export path';
            app.ExportpathEditField = uieditfield(app.ExporttoR_datamatPanel, 'text');
            app.ExportpathEditField.ValueChangedFcn = createCallbackFcn(app, @ExportpathEditFieldValueChanged, true);
            app.ConverttophysicalworldunitsasRofTdatamatPanel = uipanel(app.PostprocessingPanel);
            app.ConverttophysicalworldunitsasRofTdatamatPanel.Title = 'Convert to physical world units as "RofTdata.mat"';
            app.FPSEditFieldLabel = uilabel(app.ConverttophysicalworldunitsasRofTdatamatPanel);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.ConverttophysicalworldunitsasRofTdatamatPanel, 'numeric');
            app.FPSEditField.Limits = [1 Inf];
            app.FPSEditField.ValueChangedFcn = createCallbackFcn(app, @FPSEditFieldValueChanged, true);
            app.FPSEditField.Value = 1000000;
            app.um2pxEditFieldLabel = uilabel(app.ConverttophysicalworldunitsasRofTdatamatPanel);
            app.um2pxEditFieldLabel.Text = 'um2px';
            app.um2pxEditField = uieditfield(app.ConverttophysicalworldunitsasRofTdatamatPanel, 'numeric');
            app.um2pxEditField.Limits = [0.001 Inf];
            app.um2pxEditField.ValueChangedFcn = createCallbackFcn(app, @um2pxEditFieldValueChanged, true);
            app.um2pxEditField.Value = 3.2;
            app.ExportButton_2 = uibutton(app.ConverttophysicalworldunitsasRofTdatamatPanel, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.FitRmaxafterframeEditFieldLabel = uilabel(app.ConverttophysicalworldunitsasRofTdatamatPanel);
            app.FitRmaxafterframeEditFieldLabel.Text = 'Fit Rmax after frame#';
            app.FitRmaxafterframeEditField = uieditfield(app.ConverttophysicalworldunitsasRofTdatamatPanel, 'numeric');
            app.FitRmaxafterframeEditField.Limits = [3 Inf];
            app.FitRmaxafterframeEditField.ValueDisplayFormat = '%.0f';
            app.FitRmaxafterframeEditField.ValueChangedFcn = createCallbackFcn(app, @FitRmaxafterframeEditFieldValueChanged, true);
            app.FitRmaxafterframeEditField.Value = 11;
            app.ImagesPanel = uipanel(app.GridLayout);
            app.ImagesPanel.Title = 'Images';
            app.ImageFolderButton = uibutton(app.ImagesPanel, 'push');
            app.ImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImageFileButtonPushed, true);
            app.ImageFolderButton.Text = 'Image Folder';
            app.ImageformatDropDownLabel = uilabel(app.ImagesPanel);
            app.ImageformatDropDownLabel.Text = 'Image format';
            app.ImageformatDropDown = uidropdown(app.ImagesPanel);
            app.ImageformatDropDown.Items = {'tiff', 'tif', 'jpeg', 'bmp', 'png', 'jpg'};
            app.ImageformatDropDown.Value = 'tiff';
            app.ImagebitdepthDropDownLabel = uilabel(app.ImagesPanel);
            app.ImagebitdepthDropDownLabel.Text = 'Image bit depth';
            app.ImagebitdepthDropDown = uidropdown(app.ImagesPanel);
            app.ImagebitdepthDropDown.Items = {'8', '10', '12', '16', '24', '32', '48'};
            app.ImagebitdepthDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagebitdepthDropDownValueChanged, true);
            app.ImagebitdepthDropDown.Value = '16';
            app.EditField = uieditfield(app.ImagesPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @ImageFileButtonPushed, true);
            app.EditField.Editable = 'off';
            app.LoadexistingR_datamatButton = uibutton(app.GridLayout, 'push');
            app.LoadexistingR_datamatButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingR_datamatButtonPushed, true);
            app.LoadexistingR_datamatButton.Text = 'Load existing R_data.mat';
            app.PreturntheThresholdRemovingfactorslidertomaketheLabel = uilabel(app.GridLayout);
            app.PreturntheThresholdRemovingfactorslidertomaketheLabel.Text = {'Adjust the sliders to distinguish'; 'the bubble (white) and background (black)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_source_code_1_19

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end